classdef ASPEBI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel1                          matlab.ui.container.Panel
        Panel1Grid                      matlab.ui.container.GridLayout
        Exportar                        matlab.ui.control.Button
        Fecha                           matlab.ui.control.Label
        ASPEBI1                         matlab.ui.control.Image
        Inicio                          matlab.ui.control.Button
        Fecha2                          matlab.ui.control.Label
        ReproduccionPanel1              matlab.ui.control.Button
        Protocolo                       matlab.ui.control.Button
        Registro                        matlab.ui.control.Button
        Panel2                          matlab.ui.container.Panel
        Panel2Grid                      matlab.ui.container.GridLayout
        INICIOLabel                     matlab.ui.control.Label
        Opciones                        matlab.ui.container.TabGroup
        InicioOpc                       matlab.ui.container.Tab
        InicioOpcGrid                   matlab.ui.container.GridLayout
        UsermanualButton                matlab.ui.control.Button
        Intro                           matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Contctenos                      matlab.ui.control.Button
        Comoempezar                     matlab.ui.control.Button
        RegisterTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ResearcherTab                   matlab.ui.container.Tab
        GridInvestigador                matlab.ui.container.GridLayout
        EdadInvestigador                matlab.ui.control.EditField
        EdadLabel                       matlab.ui.control.Label
        IDInvestigador                  matlab.ui.control.EditField
        NodedocumentoLabel              matlab.ui.control.Label
        NivelmximodeescolaridadInvestigador  matlab.ui.control.DropDown
        MaximumlevelofeducationLabel    matlab.ui.control.Label
        PEBIInvestigador                matlab.ui.control.Switch
        PEBISwitch_3Label               matlab.ui.control.Label
        ZRInvestigador                  matlab.ui.control.DropDown
        ResidencezoneLabel              matlab.ui.control.Label
        SexoInvestigador                matlab.ui.control.DropDown
        GenderLabel                     matlab.ui.control.Label
        FNInvestigador                  matlab.ui.control.DatePicker
        BirthdateLabel                  matlab.ui.control.Label
        SAInvestigador                  matlab.ui.control.EditField
        SecondlastnameLabel             matlab.ui.control.Label
        PAInvestigador                  matlab.ui.control.EditField
        FirstsurnameLabel               matlab.ui.control.Label
        SNInvestigador                  matlab.ui.control.EditField
        MiddlenameLabel                 matlab.ui.control.Label
        PNInvestigador                  matlab.ui.control.EditField
        FirstnameLabel                  matlab.ui.control.Label
        TipodedocumentoLabel_3          matlab.ui.control.Label
        TIDInvestigador                 matlab.ui.control.DropDown
        OkInvestigador                  matlab.ui.control.EditField
        RestablecerInvestigador         matlab.ui.control.Button
        RegistrarInvestigador           matlab.ui.control.Button
        MedicoTab                       matlab.ui.container.Tab
        GridMedico                      matlab.ui.container.GridLayout
        ProcedimientoMedico             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        NmerodefacturaLabel             matlab.ui.control.Label
        NFacturaProcedimientoMedico     matlab.ui.control.TextArea
        CdigodeIPSLabel                 matlab.ui.control.Label
        CIPSProcedimientoMedico         matlab.ui.control.EditField
        ProcedurefeeLabel               matlab.ui.control.Label
        VNPProcedimientoMedico          matlab.ui.control.NumericEditField
        PersonalAProcedimientoMedico    matlab.ui.control.DropDown
        PersonalqueatiendeLabel         matlab.ui.control.Label
        FPProcedimientoMedico           matlab.ui.control.DropDown
        FinalidaddelprocedimientoLabel  matlab.ui.control.Label
        DiagnosticorelacionadoLabel     matlab.ui.control.Label
        DRProcedimientoMedico           matlab.ui.control.EditField
        NmerodeautorizacinLabel         matlab.ui.control.Label
        NAutorizacionProcedimientoMedico  matlab.ui.control.TextArea
        SurgicalprocedureLabel          matlab.ui.control.Label
        FRAQProcedimientoMedico         matlab.ui.control.EditField
        ComplicacinLabel                matlab.ui.control.Label
        ComplicacionProcedimientoMedico  matlab.ui.control.EditField
        MaindiagnosisLabel              matlab.ui.control.Label
        DPProcedimientoMedico           matlab.ui.control.EditField
        mbitodeprocedimientoLabel       matlab.ui.control.Label
        APRProcedimientoMedico          matlab.ui.control.DropDown
        ProcedurecodeLabel              matlab.ui.control.Label
        CProcedimientoProcedimientoMedico  matlab.ui.control.EditField
        EnviarProcedimientoMedico       matlab.ui.control.Button
        RestablecerProcedimientoMedico  matlab.ui.control.Button
        OkProecedimientoMedico          matlab.ui.control.EditField
        ConsultaMedico                  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ADVFCConsultaMedico             matlab.ui.control.Label
        ADVVNPConsultaMedico            matlab.ui.control.Label
        ADVVCConsultaMedico             matlab.ui.control.Label
        ADVVCMConsultaMedico            matlab.ui.control.Label
        ADVCDR3ConsutlaMedico           matlab.ui.control.Label
        ADVCDR2ConsutlaMedico           matlab.ui.control.Label
        ADVCDR1ConsutlaMedico           matlab.ui.control.Label
        ADVCDPConsultaMedico            matlab.ui.control.Label
        PayablevalueLabel               matlab.ui.control.Label
        ValorNConsultaMedico            matlab.ui.control.NumericEditField
        ValorcmoderadoraLabel           matlab.ui.control.Label
        ValorCMConsultaMedico           matlab.ui.control.NumericEditField
        ConsultationvalueLabel          matlab.ui.control.Label
        ValorCConsultaMedico            matlab.ui.control.NumericEditField
        CConsultaLabel                  matlab.ui.control.Label
        CConsultaMedico                 matlab.ui.control.EditField
        No3EditFieldLabel               matlab.ui.control.Label
        CDR3ConsultaMedico              matlab.ui.control.EditField
        No2EditFieldLabel               matlab.ui.control.Label
        CDR2ConsultaMedico              matlab.ui.control.EditField
        No1EditFieldLabel               matlab.ui.control.Label
        CDR1Consultamedico              matlab.ui.control.EditField
        DiagnosictypeLabel              matlab.ui.control.Label
        TDiagnosticoMedico              matlab.ui.control.DropDown
        ExternalcauseLabel              matlab.ui.control.Label
        CExternaMedico                  matlab.ui.control.DropDown
        ConsultationpLabel              matlab.ui.control.Label
        FConsultaMedico                 matlab.ui.control.DropDown
        CdigodediagnsticoprincipalCDPLabel  matlab.ui.control.Label
        CDPConsultaMedico               matlab.ui.control.EditField
        NdeautorizacinLabel             matlab.ui.control.Label
        NAutorizacionConsultaMedico     matlab.ui.control.TextArea
        OkConsultaMedico                matlab.ui.control.EditField
        CDRLabel                        matlab.ui.control.Label
        EnviarConsultaMedico            matlab.ui.control.Button
        RestablecerConsultaMedico       matlab.ui.control.Button
        RegistrodeparticipanteMedico    matlab.ui.container.Panel
        GridRegistroMedico              matlab.ui.container.GridLayout
        UEdadMedico                     matlab.ui.control.Label
        PrimernombreLabel               matlab.ui.control.Label
        PNMedico                        matlab.ui.control.EditField
        NodedocumentoLabel_2            matlab.ui.control.Label
        IDMedico                        matlab.ui.control.EditField
        UnidaddeedadLabel               matlab.ui.control.Label
        UEMedico                        matlab.ui.control.EditField
        EdadLabel_2                     matlab.ui.control.Label
        EdadMedico                      matlab.ui.control.EditField
        PEBISwitchLabel                 matlab.ui.control.Label
        PEBIMedico                      matlab.ui.control.Switch
        EntidadadministradoraLabel      matlab.ui.control.Label
        CEAMedico                       matlab.ui.control.EditField
        ResidencezonreLabel             matlab.ui.control.Label
        ZRMedico                        matlab.ui.control.DropDown
        GenderLabel_2                   matlab.ui.control.Label
        SexoMedico                      matlab.ui.control.DropDown
        TowncodeLabel                   matlab.ui.control.Label
        CodMMedico                      matlab.ui.control.NumericEditField
        StatecodeLabel                  matlab.ui.control.Label
        CodDMedico                      matlab.ui.control.NumericEditField
        TUMedico                        matlab.ui.control.DropDown
        UsertypeLabel                   matlab.ui.control.Label
        BirthdateLabel_2                matlab.ui.control.Label
        FNMedico                        matlab.ui.control.DatePicker
        SecondsurnameLabel              matlab.ui.control.Label
        SAMedico                        matlab.ui.control.EditField
        FirstsurnameLabel_2             matlab.ui.control.Label
        PAMedico                        matlab.ui.control.EditField
        MiddlenameLabel_2               matlab.ui.control.Label
        SNMedico                        matlab.ui.control.EditField
        TIDMedico                       matlab.ui.control.DropDown
        TipodedocumentoLabel_4          matlab.ui.control.Label
        EnviarParticipanteMedico        matlab.ui.control.Button
        RestablecerParticipanteMedico   matlab.ui.control.Button
        OkParticipanteMedico            matlab.ui.control.EditField
        ProtocolTab                     matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        EnteranthropometricdataButton   matlab.ui.control.Button
        Indicacion                      matlab.ui.control.Label
        StageLabel                      matlab.ui.control.Label
        PrimerPaso_7                    matlab.ui.control.CheckBox
        PrimerPaso_9                    matlab.ui.control.CheckBox
        PrimerPaso_8                    matlab.ui.control.CheckBox
        PrimerPaso_6                    matlab.ui.control.CheckBox
        PrimerPaso_5                    matlab.ui.control.CheckBox
        PrimerPaso_4                    matlab.ui.control.CheckBox
        PrimerPaso_2                    matlab.ui.control.CheckBox
        PrimerPaso_3                    matlab.ui.control.CheckBox
        PrimerPaso                      matlab.ui.control.CheckBox
        NextButton                      matlab.ui.control.StateButton
        PreviousButton                  matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
        ReproductionTab                 matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        ReportPanel                     matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        VisualizarInforme               matlab.ui.control.Button
        JointanglegraphsPanel           matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        AduccinAbduccinTobillo          matlab.ui.control.CheckBox
        AduccinAbduccinRodilla          matlab.ui.control.CheckBox
        Ampliar                         matlab.ui.control.Button
        DorsiflexinPlantiflexinTobillo  matlab.ui.control.CheckBox
        RotacinTobillo                  matlab.ui.control.CheckBox
        FlexinExtensinRodilla           matlab.ui.control.CheckBox
        RotacinRodilla                  matlab.ui.control.CheckBox
        AduccinAbduccinCadera           matlab.ui.control.CheckBox
        FlexinExtensinCadera            matlab.ui.control.CheckBox
        RotacinCadera                   matlab.ui.control.CheckBox
        Angulosgraficas_2               matlab.ui.control.DropDown
        Axesgrafica                     matlab.ui.control.UIAxes
        Reproduccin3DPanel              matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        IniciarReproduccion             matlab.ui.control.Button
        Tiempo                          matlab.ui.control.Slider
        Reproduccion                    matlab.ui.control.UIAxes
        SpatiotemporalvariablesPanel    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        CalcularEspaciotemporales_2     matlab.ui.control.Button
        msLabel                         matlab.ui.control.Label
        ppmLabel                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        LeftstridelengthEditField       matlab.ui.control.NumericEditField
        LeftstridelengthEditFieldLabel  matlab.ui.control.Label
        CalcularEspaciotemporales       matlab.ui.control.Button
        RigthstridelengthEditField      matlab.ui.control.NumericEditField
        RigthstridelengthEditFieldLabel  matlab.ui.control.Label
        StepwidthEditField              matlab.ui.control.NumericEditField
        StepwidthEditFieldLabel         matlab.ui.control.Label
        Velocidaddemarcha               matlab.ui.control.NumericEditField
        GaitspeedLabel                  matlab.ui.control.Label
        CadenceEditField                matlab.ui.control.NumericEditField
        CadenceEditFieldLabel           matlab.ui.control.Label
        SteplengthEditField             matlab.ui.control.NumericEditField
        SteplengthEditFieldLabel        matlab.ui.control.Label
        ImportDataPanel                 matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        TypeoffileButtonGroup           matlab.ui.container.ButtonGroup
        VariablesImportar               matlab.ui.control.RadioButton
        PEBIImportar                    matlab.ui.control.RadioButton
        CargarArchivoMVNX               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.UIFigure.WindowState = 'maximized';
            datetime.setDefaultFormats('defaultdate','dd/MM/yyyy')
            Fechae=datetime('today');
            app.Fecha.Text=datestr(Fechae,'mm/dd/yy');
            global clic
            clic = 0;
            %Deshabilitar botones
            app.CalcularEspaciotemporales.Enable='off';
            app.CalcularEspaciotemporales_2.Enable='off';
            app.IniciarReproduccion.Enable='off';
            app.Ampliar.Enable='off';
            app.Angulosgraficas_2.Enable='off';
            app.VisualizarInforme.Enable='off';
            
            proto=imread("Proto.jpeg");
            imshow(proto, 'Parent', app.UIAxes);
            
        end

        % Button pushed function: Comoempezar
        function ComoempezarPushed(app, event)
            uiconfirm(app.UIFigure,{'Esta interfaz esta compuesta por 4 paneles, que son:',...
                '',...
                '1. Inicio: Aquí está usted, en este panel podrá encontrar un breve introducción a la herramienta.',...
                '',...
                '2. Registro: Allí podrá registrar la información personal y médica del participante.',...
                '',...
                '3. Protocolo: Allí encontrará el paso a paso para la toma de datos y el uso del equipo.',...
                '',...
                '4. Reproducción: en este panel podrá visualizar la reproducción 3D del movimiento, parámetros espaciotemporales de la marcha y variables cinemáticas'},'¿Como Comenzar?' , 'Icon','question' );
        end

        % Button pushed function: Contctenos
        function ContctenosPushed(app, event)
            uiconfirm(app.UIFigure,{'Se puede comunicar con nosotros para cualquier inquietud o sugerencia a los siguientes correos:',...
                '1. Pruedag@unbosque.edu.co','2. Rscardenas@unbosque.edu.co'},'Contactenos',...
                'Icon','info');
            
        end

        % Button pushed function: Inicio
        function InicioButtonPushed(app, event)
            app.Opciones.SelectedTab = app.InicioOpc;
            app.INICIOLabel.Text='START';
        end

        % Button pushed function: Registro
        function RegistroButtonPushed(app, event)
            app.Opciones.SelectedTab = app.RegisterTab;
            app.INICIOLabel.Text='REGISTER';
        end

        % Button pushed function: Protocolo
        function ProtocoloButtonPushed(app, event)
            app.Opciones.SelectedTab = app.ProtocolTab;
            app.INICIOLabel.Text='PROTOCOL';
        end

        % Button pushed function: ReproduccionPanel1
        function ReproduccionPanel1ButtonPushed(app, event)
            app.Opciones.SelectedTab = app.ReproductionTab;
            app.INICIOLabel.Text='REPRODUCTION AND VISUALIZATION';
        end

        % Button pushed function: CargarArchivoMVNX
        function CargarArchivoMVNXButtonPushed(app, event)
            global sesion frec Datos_segmentos Datos_articulaciones fullpathname
            %Carga de Archivo(.mvnx)
            [filename, pathname] = uigetfile({'*.mvnx'},'File Selector');
            if filename == 0
                uiconfirm(app.UIFigure,'Advertencia, no se cargo el archivo .mvnx ','Importación de datos',...
                    'Icon','warning');
                
            else
                
                %Identificar si es .mvnx
                if filename(1,length(filename)-4:length(filename))=='.mvnx'
                    %LLamar archivos y cargar toda la información de la sesión
                    fullpathname = strcat(pathname,filename);
                    
                    d = uiprogressdlg(app.UIFigure,'Title','Espere mientras se carga la información',...
                        'Message','Opening the application');
                    pause(.5)
                    
                    d.Value = .33;
                    d.Message = 'Cargando Información';
                    pause(1)
                    
                    sesion = load_mvnx_v2(fullpathname);
                    if isfield(sesion,'segmentData') && isstruct(sesion.segmentData) && isfield(sesion,'metaData')
                        Datos_segmentos = sesion.segmentData;
                        frec = str2double(sesion.metaData.subject_frameRate);
                        app.IniciarReproduccion.Enable='on';
                    else
                        app.IniciarReproduccion.Enable='off';
                    end
                    if isfield(sesion,'jointData') && isstruct(sesion.jointData)
                        Datos_articulaciones = sesion.jointData;
                        app.Angulosgraficas_2.Enable='on';
                        app.Ampliar.Enable='on';
                    else
                        app.Angulosgraficas_2.Enable='off';
                        app.Ampliar.Enable='off';
                    end
                    
                    d.Value = .67;
                    d.Message = 'Procesando Información';
                    pause(1)
                    
                                       
                    if isfield(sesion,'footContact') && isstruct(sesion.jointData)
                        app.CalcularEspaciotemporales.Enable='on';
                        app.CalcularEspaciotemporales_2.Enable='on';
                        app.VisualizarInforme.Enable='on';
                    else
                        app.CalcularEspaciotemporales.Enable='off';
                        app.CalcularEspaciotemporales_2.Enable='off';
                        app.VisualizarInforme.Enable='off';
                    end
                    
                    
                    d.Value = 1;
                    d.Message = 'Carga Completa';
                    pause(1)
                    close(d)
                    
                else
                    uiconfirm(app.UIFigure,'El archivo que escogio es el incorrecto, se debe cargar archivo .mvnx','Error de importación de datos',...
                        'Icon','error');
                    
                end
                
            end


        end

        % Button pushed function: IniciarReproduccion
        function IniciarReproduccionPushed(app, event)
            global  frec Datos_segmentos
            tobilloAl=0.085;
            suela=0.01;
            
            %L5
            L5=Datos_segmentos(2).position;
            %Pelvis
            pelvis=Datos_segmentos(1).position;
            %Articulacion Cadera-Muslo Derecha
            ACMD=Datos_segmentos(16).position;
            %Articulacion Cadera-Muslo Izquierda
            ACMI=Datos_segmentos(20).position;
            %Articulacion de la rodilla Derecha
            ARD=Datos_segmentos(17).position;
            %Articulacion de la rodilla Izquierda
            ARI=Datos_segmentos(21).position;
            %Articulacion del tobillo Derecho
            ATD=Datos_segmentos(18).position;
            %Articulacion del tobillo Izquierdo
            ATI=Datos_segmentos(22).position;
            %Dedo del pie Derecho
            DedoD=Datos_segmentos(19).position;
            %Dedo del pie Izquierdo
            DedoI=Datos_segmentos(23).position;
            %Talon del pie Derecho
            TalonD=Datos_segmentos(18).position;
            TalonD(:,3)=TalonD(:,3)-tobilloAl-suela;
            %Talon del pie Derecho
            TalonI=Datos_segmentos(22).position;
            TalonI(:,3)=TalonI(:,3)-tobilloAl-suela;
            %Segmentos
            %l5
            L5X=[L5(:,1),pelvis(:,1)];
            L5Y=[L5(:,2),pelvis(:,2)];
            L5Z=[L5(:,3),pelvis(:,3)];
            %Cadera Derecha
            CaderaXd=[pelvis(:,1),ACMD(:,1)];
            CaderaYd=[pelvis(:,2),ACMD(:,2)];
            CaderaZd=[pelvis(:,3),ACMD(:,3)];
            %Cadera Izquierda
            CaderaXi=[pelvis(:,1),ACMI(:,1)];
            CaderaYi=[pelvis(:,2),ACMI(:,2)];
            CaderaZi=[pelvis(:,3),ACMI(:,3)];
            %Muslo Derecho
            MusloXd=[ACMD(:,1),ARD(:,1)];
            MusloYd=[ACMD(:,2),ARD(:,2)];
            MusloZd=[ACMD(:,3),ARD(:,3)];
            %Muslo Izquierda
            MusloXi=[ACMI(:,1),ARI(:,1)];
            MusloYi=[ACMI(:,2),ARI(:,2)];
            MusloZi=[ACMI(:,3),ARI(:,3)];
            %Pierna Derecha
            PiernaXd=[ARD(:,1),ATD(:,1)];
            PiernaYd=[ARD(:,2),ATD(:,2)];
            PiernaZd=[ARD(:,3),ATD(:,3)];
            %Pierna Izquierda
            PiernaXi=[ARI(:,1),ATI(:,1)];
            PiernaYi=[ARI(:,2),ATI(:,2)];
            PiernaZi=[ARI(:,3),ATI(:,3)];
            %Pie Derecho
            PieXd=[ATD(:,1),DedoD(:,1)];
            PieYd=[ATD(:,2),DedoD(:,2)];
            PieZd=[ATD(:,3),DedoD(:,3)];
            %Pie Derecho
            PieXi=[ATI(:,1),DedoI(:,1)];
            PieYi=[ATI(:,2),DedoI(:,2)];
            PieZi=[ATI(:,3),DedoI(:,3)];
            %Talon Derecho
            TalXd=[ATD(:,1),TalonD(:,1)];
            TalYd=[ATD(:,2),TalonD(:,2)];
            TalZd=[ATD(:,3),TalonD(:,3)];
            %Talon Izquierdo
            TalXi=[ATI(:,1),TalonI(:,1)];
            TalYi=[ATI(:,2),TalonI(:,2)];
            TalZi=[ATI(:,3),TalonI(:,3)];
            %Planta Derecha
            PlaXd=[TalonD(:,1),DedoD(:,1)];
            PlaYd=[TalonD(:,2),DedoD(:,2)];
            PlaZd=[TalonD(:,3),DedoD(:,3)];
            %Planta Izquierda
            PlaXi=[TalonI(:,1),DedoI(:,1)];
            PlaYi=[TalonI(:,2),DedoI(:,2)];
            PlaZi=[TalonI(:,3),DedoI(:,3)];
            
            
            %Graficar
            colorA=[0 0.4470 0.7410];
            TA=4;
            colorL=[0 0.6450 0.9330];
            TL=5;
            %Linea de Tiempo
            app.Tiempo.Limits=[round(0*(1/frec)),round(length(DedoI)*(1/frec)+(10*(1/frec)))];
            %         if app.Tiempo.Value<=0
            %         app.Tiempo.Value=1*(1/frec);
            %         end
            %REPRODUCCIÓN 3d----------------------------------------------------------
            boxtime=1*(1/frec);
            n=app.Tiempo.Value;
            
            while n<=length(DedoI)
                
                app.Tiempo.Value=boxtime;
                n=round(app.Tiempo.Value*frec);
                
                %Articulaciones-----------------------------------------------------------------------------
                plot3(app.Reproduccion,pelvis(n,1),pelvis(n,2),pelvis(n,3),'o','LineWidth' , 3,'color',colorA)
                hold(app.Reproduccion, 'on')
                plot3(app.Reproduccion,L5(n,1),L5(n,2),L5(n,3),'*','LineWidth' , TA,'color',colorA)
                plot3(app.Reproduccion,ACMD(n,1),ACMD(n,2),ACMD(n,3),'*','LineWidth' , TA,'color',colorA)
                plot3(app.Reproduccion,ACMI(n,1),ACMI(n,2),ACMI(n,3),'*','LineWidth' , TA,'color',colorA)
                plot3(app.Reproduccion,ARD(n,1),ARD(n,2),ARD(n,3),'*','LineWidth' , TA,'color',colorA)
                plot3(app.Reproduccion,ARI(n,1),ARI(n,2),ARI(n,3),'*','LineWidth' , TA,'color',colorA)
                plot3(app.Reproduccion,ATD(n,1),ATD(n,2),ATD(n,3),'*','LineWidth' , TA,'color',colorA)
                plot3(app.Reproduccion,ATI(n,1),ATI(n,2),ATI(n,3),'*','LineWidth' , TA,'color',colorA)
                
                plot3(app.Reproduccion,DedoD(n,1),DedoD(n,2),DedoD(n,3),'*','LineWidth' , 2,'color',colorL)
                plot3(app.Reproduccion,DedoI(n,1),DedoI(n,2),DedoI(n,3),'*','LineWidth' , 2,'color',colorL)
                plot3(app.Reproduccion,TalonD(n,1),TalonD(n,2),TalonD(n,3),'*','LineWidth' , 2,'color',colorL)
                plot3(app.Reproduccion,TalonI(n,1),TalonI(n,2),TalonI(n,3),'*','LineWidth' , 2,'color',colorL)
                
                %Segmentos---------------------------------------------------------------------------------
                %L5
                plot3(app.Reproduccion,L5X(n,:),L5Y(n,:),L5Z(n,:),'LineWidth' ,TL,'color',colorL)
                %Cadera
                plot3(app.Reproduccion,CaderaXd(n,:),CaderaYd(n,:),CaderaZd(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,CaderaXi(n,:),CaderaYi(n,:),CaderaZi(n,:),'LineWidth' , TL,'color',colorL)
                %Muslo
                plot3(app.Reproduccion,MusloXd(n,:),MusloYd(n,:),MusloZd(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,MusloXi(n,:),MusloYi(n,:),MusloZi(n,:),'LineWidth' , TL,'color',colorL)
                %Pierna
                plot3(app.Reproduccion,PiernaXd(n,:),PiernaYd(n,:),PiernaZd(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,PiernaXi(n,:),PiernaYi(n,:),PiernaZi(n,:),'LineWidth' , TL,'color',colorL)
                %Pie
                plot3(app.Reproduccion,PieXd(n,:),PieYd(n,:),PieZd(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,PieXi(n,:),PieYi(n,:),PieZi(n,:),'LineWidth' , TL,'color',colorL)
                %Talon
                plot3(app.Reproduccion,TalXd(n,:),TalYd(n,:),TalZd(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,TalXi(n,:),TalYi(n,:),TalZi(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,PlaXd(n,:),PlaYd(n,:),PlaZd(n,:),'LineWidth' , TL,'color',colorL)
                plot3(app.Reproduccion,PlaXi(n,:),PlaYi(n,:),PlaZi(n,:),'LineWidth' , TL,'color',colorL)
                
                xlim(app.Reproduccion,[L5(n,1)-2 L5(n,1)+2]);ylim(app.Reproduccion,[L5(n,2)-2 L5(n,2)+2]);zlim(app.Reproduccion,[L5(n,3)-2 L5(n,3)+1]);
                xlabel(app.Reproduccion,'X');ylabel(app.Reproduccion,'Y');zlabel(app.Reproduccion,'Z')
                
                campos(app.Reproduccion,[40,60,20])
                
                grid(app.Reproduccion, 'on')
                %axis equal
                hold(app.Reproduccion, 'off')
                drawnow
                n=round(app.Tiempo.Value*frec)+10;
                boxtime=n*(1/frec);
            end
        end

        % Button pushed function: RegistrarInvestigador
        function RegistrarInvestigadorButtonPushed(app, event)
            if (strlength(app.TIDInvestigador.Value)>3||strlength(app.SexoInvestigador.Value)>3||strlength(app.ZRInvestigador.Value)>9||(isempty(app.IDInvestigador.Value))||(isempty(app.PNInvestigador.Value))||(isempty(app.PAInvestigador.Value))||(isempty(app.TUMedico.Value))||(isempty(app.FNInvestigador.Value))||(isempty(app.EdadInvestigador.Value)))
                
                app.OkInvestigador.Value=sprintf('There are required fields left');
                
                if(strlength(app.TIDInvestigador.Value)>3)
                    app.TIDInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.TIDInvestigador.BackgroundColor=[0.96,0.96,0.96];
                end
                if(isempty(app.IDInvestigador.Value))
                    app.IDInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.IDInvestigador.BackgroundColor=[1,1,1];
                end
                if(isempty(app.PNInvestigador.Value))
                    app.PNInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.PNInvestigador.BackgroundColor=[1,1,1];
                end
                if(isempty(app.PAInvestigador.Value))
                    app.PAInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.PAInvestigador.BackgroundColor=[1,1,1];
                end
                if(strlength(app.SexoInvestigador.Value)>3)
                    app.SexoInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.SexoInvestigador.BackgroundColor=[0.96,0.96,0.96];
                end
                if(isempty(app.EdadInvestigador.Value))
                    app.EdadInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.EdadInvestigador.BackgroundColor=[0.96,0.96,0.96];
                end
                if(strlength(app.ZRInvestigador.Value)>3)
                    app.ZRInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.ZRInvestigador.BackgroundColor=[0.96,0.96,0.96];
                end
                if(app.NivelmximodeescolaridadInvestigador.Value=='--Seleccione--')  %#ok<*BDSCA>
                    app.NivelmximodeescolaridadInvestigador.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.NivelmximodeescolaridadInvestigador.BackgroundColor=[0.96,0.96,0.96];
                end
            else
                app.OkInvestigador.Value=sprintf('Information registered');
            end
        end

        % Button pushed function: RestablecerInvestigador
        function RestablecerInvestigadorButtonPushed(app, event)
            app.OkInvestigador.Value='';
            app.TIDInvestigador.Value='--Seleccione--';
            app.PNInvestigador.Value='';
            app.SNInvestigador.Value='';
            app.PAInvestigador.Value='';
            app.SAInvestigador.Value='';
            app.SexoInvestigador.Value='--Seleccione--';
            app.ZRInvestigador.Value='--Seleccione--';
            app.IDInvestigador.Value='';
            app.EdadInvestigador.Value='';
            app.NivelmximodeescolaridadInvestigador.Value='--Seleccione--';
            app.TIDInvestigador.BackgroundColor=[1,1,1];
            app.IDInvestigador.BackgroundColor=[1,1,1];
            app.PNInvestigador.BackgroundColor=[1,1,1];
            app.PAInvestigador.BackgroundColor=[1,1,1];
            app.SexoInvestigador.BackgroundColor=[1,1,1];
            app.EdadInvestigador.BackgroundColor=[1,1,1];
            app.ZRInvestigador.BackgroundColor=[1,1,1];
            app.NivelmximodeescolaridadInvestigador.BackgroundColor=[1,1,1];
        end

        % Button pushed function: EnviarParticipanteMedico
        function EnviarParticipanteMedicoButtonPushed(app, event)
            global TIDe Edad
            TIDe=app.TIDMedico.Value;
            
            if (strlength(app.TIDMedico.Value)>3||strlength(app.SexoMedico.Value)>3||strlength(app.ZRMedico.Value)>9||strlength(app.TUMedico.Value)>3||(isempty(app.IDMedico.Value))||(isempty(app.PNMedico.Value))||(isempty(app.PAMedico.Value))||(isempty(app.TUMedico.Value))||(app.CodDMedico.Value==0)||(app.CodMMedico.Value==0))
                
                app.OkParticipanteMedico.Value=sprintf('Hay campos obligatorios sin completar');
                
                if(strlength(app.TIDMedico.Value)>3)
                    app.TIDMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.TIDMedico.BackgroundColor=[1,1,1];
                end
                if(isempty(app.IDMedico.Value))
                    app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.IDMedico.BackgroundColor=[1,1,1];
                end
                if(isempty(app.PNMedico.Value))
                    app.PNMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.PNMedico.BackgroundColor=[1,1,1];
                end
                if(isempty(app.PAMedico.Value))
                    app.PAMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.PAMedico.BackgroundColor=[1,1,1];
                end
                if(strlength(app.SexoMedico.Value)>3)
                    app.SexoMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.SexoMedico.BackgroundColor=[1,1,1];
                end
                if(strlength(app.ZRMedico.Value)>9)
                    app.ZRMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.ZRMedico.BackgroundColor=[1,1,1];
                end
                if(app.CodDMedico.Value==0)
                    app.CodDMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.CodDMedico.BackgroundColor=[1,1,1];
                end
                if(app.CodMMedico.Value==0)
                    app.CodMMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.CodMMedico.BackgroundColor=[1,1,1];
                end
                if(strlength(app.TUMedico.Value)>3)
                    app.TUMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.TUMedico.BackgroundColor=[1,1,1];
                end
                
                
            else
                app.TIDMedico.BackgroundColor=[0.96,0.96,0.96];app.IDMedico.BackgroundColor=[1,1,1];app.PNMedico.BackgroundColor=[1,1,1];app.PAMedico.BackgroundColor=[1,1,1];app.SexoMedico.BackgroundColor=[0.96,0.96,0.96];app.ZRMedico.BackgroundColor=[0.96,0.96,0.96];app.TUMedico.BackgroundColor=[0.96,0.96,0.96];app.CodDMedico.BackgroundColor=[1,1,1];app.CodMMedico.BackgroundColor=[1,1,1];
                
                switch app.TIDMedico.Value
                    case "CC" %--------------------------------------------- Cédula
                        IDe= str2double(app.IDMedico.Value);
                        if ((isnan(IDe))==0)
                            if (numel(num2str(IDe))<=10)
                                if (Edad(1)>=18)
                                    app.OkParticipanteMedico.Value=sprintf('Registrado');
                                        app.IDMedico.BackgroundColor=[1,1,1];
                                        app.EdadMedico.BackgroundColor=[1,1,1];
                                else
                                    app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                    app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                                end
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "CE" %-------------------------------Cedula de extranjeria
                        IDe= str2double(app.IDMedico.Value);
                        if ((isnan(IDe))==0)
                            if (numel(num2str(IDe))<=6)
                                if (Edad(1)>18 )
                                    app.OkParticipanteMedico.Value=sprintf('Registrado');
                                    app.IDMedico.BackgroundColor=[1,1,1];
                                    app.EdadMedico.BackgroundColor=[1,1,1];
                                else
                                    app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                    app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                                end
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "CD"%-----------------------------------------CarnéDiplomático
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=16)
                            if (Edad(1)>18)
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "PA" %---------------------------------------------Pasaporte
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=16)
                            if (Edad(1)>18)
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "SC"%-----------------------------------------Salvoconducto
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=16)
                            if (Edad(1)>18)
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "PE"%----------------------------------------PermisoEspecial
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=15)
                            if (Edad(1)>18)
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "RC"%----------------------------------------RegistroCivil
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=11)
                            if ((Edad(1)<18)||(UEe==2)||(UEe==3))
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkConsultaMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "TI" %--------------------------------Tarjeta de identidad
                        IDe= str2double(app.IDMedico.Value);
                        if ((isnan(IDe))==0)
                            if (numel(num2str(IDe))<=11)
                                if (Edad(1)<18)
                                    app.OkParticipanteMedico.Value=sprintf('Registrado');
                                    app.EdadMedico.BackgroundColor=[1,1,1];
                                    app.IDMedico.BackgroundColor=[1,1,1];
                                else
                                    app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                    app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                                end
                                
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                                
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                            
                        end
                    case "CN"%-------------------------------------CertificadoNacidoVivo
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=9)
                            if ((Edad(1)<18)||(UEe==2)||(UEe==3))
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "AS"%-------------------------------------AdultoSinIdentificar
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=10)
                            if (Edad(1)>17)
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                    case "MS"%-------------------------------------MenorSinIdentificar
                        IDe= str2double(app.IDMedico.Value);
                        if (numel(IDe)<=12)
                            if ((Edad(1)<18)||(UEe==2)||(UEe==3))
                                app.OkParticipanteMedico.Value=sprintf('Registrado');
                                app.IDMedico.BackgroundColor=[1,1,1];
                                app.EdadMedico.BackgroundColor=[1,1,1];
                            else
                                app.OkParticipanteMedico.Value=sprintf('Verifique información');
                                app.EdadMedico.BackgroundColor=[0.92,0.29,0.37];
                            end
                        else
                            app.OkParticipanteMedico.Value=sprintf('Verifique información');
                            app.IDMedico.BackgroundColor=[0.92,0.29,0.37];
                        end
                        
                end
            end
        end

        % Button pushed function: RestablecerParticipanteMedico
        function RestablecerParticipanteMedicoButtonPushed(app, event)
            app.IDMedico.Value='';
            app.PNMedico.Value='';
            app.SNMedico.Value='';
            app.PAMedico.Value='';
            app.SAMedico.Value='';
            app.SexoMedico.Value='--Seleccione--';
            app.TIDMedico.Value='--Seleccione--';
            app.TUMedico.Value='--Seleccione--';
            app.ZRMedico.Value='--Seleccione--';
            app.CodDMedico.Value=0;
            app.CodMMedico.Value=0;
            app.OkParticipanteMedico.Value=sprintf('');
            app.EdadMedico.Value='';
            app.UEMedico.Value='';
            app.EdadMedico.Value='';
            app.TIDMedico.BackgroundColor=[1,1,1];
            app.IDMedico.BackgroundColor=[1,1,1];
            app.PNMedico.BackgroundColor=[1,1,1];
            app.PAMedico.BackgroundColor=[1,1,1];
            app.SexoMedico.BackgroundColor=[1,1,1];
            app.ZRMedico.BackgroundColor=[1,1,1];
            app.CodDMedico.BackgroundColor=[1,1,1];
            app.CodMMedico.BackgroundColor=[1,1,1];
            app.TUMedico.BackgroundColor=[1,1,1];
           
        end

        % Value changed function: FNMedico
        function FNMedicoValueChanged(app, event)
            global Edad FA
            format short g
            FNe = datevec(app.FNMedico.Value);
            
            FA=datevec(datetime('today'));
            
            Edad=FA-FNe;
            if (Edad(1)>0)
                UEe=1;
                app.EdadMedico.Value=sprintf(num2str(Edad(1)));
                app.UEdadMedico.Text="(Años)";
            elseif ((Edad(1)==0)&&(Edad(2)>0))
                UEe=2;
                app.EdadMedico.Value=sprintf(num2str(Edad(2)));
                app.UEdadMedico.Text="(Meses)";
            elseif((Edad(1)==0)&&(Edad(2)==0)&&(Edad(3)>0))
                UEe=3;
                app.EdadMedico.Value=sprintf(num2str(Edad(3)));
                app.UEdadMedico.Text="(Días)";
            elseif((Edad(1)<=0)&&(Edad(2)<=0)&&(Edad(3)<=0))
                UEe=0;
            elseif(Edad(1)<0)
                UEe=0;
                
            end
            app.UEMedico.Value=sprintf(num2str(UEe));
            
        end

        % Button pushed function: EnviarConsultaMedico
        function EnviarConsultaMedicoButtonPushed(app, event)
            if (isempty(app.CDPConsultaMedico.Value)==1)
                app.OkConsultaMedico.Value = sprintf('Hay campos obligatorios sin completar');
                if(isempty(app.CDPConsultaMedico.Value)==1)
                    app.CDPConsultaMedico.BackgroundColor=[0.92,0.29,0.37];
                else
                    app.CDPConsultaMedico.BackgroundColor=[1,1,1];
                end
                
            else
                
                %Código de diagnóstico principal
                if ((strlength(app.CDPConsultaMedico.Value)<=4))
                    app.ADVCDPConsultaMedico.Text='';
                    A0=0;
                else
                    app.ADVCDPConsultaMedico.Text='El CDP no es válido';
                    A0=1;
                end
                
                %Códigos de diagnóstico relacionado
                if (strlength(app.CDR1Consultamedico.Value)<=4)
                    app.ADVCDR1ConsutlaMedico.Text='';
                    A1=0;
                else
                    app.ADVCDR1ConsutlaMedico.Text='No válido';
                    A1=1;
                end
                if (strlength(app.CDR2ConsultaMedico.Value)<=4)
                    app.ADVCDR2ConsutlaMedico.Text='';
                    A2=0;
                else
                    app.ADVCDR2ConsutlaMedico.Text='No válido';
                    A2=1;
                end
                if (strlength(app.CDR3ConsultaMedico.Value)<=4)
                    app.ADVCDR3ConsutlaMedico.Text='';
                    A3=0;
                else
                    app.ADVCDR3ConsutlaMedico.Text='No válido';
                    A3=1;
                    
                end
                
                
                % Costos
                % Cuota moderadora
                if ((isnan(app.ValorCMConsultaMedico.Value))==0)
                    if (numel(num2str(app.ValorCMConsultaMedico.Value))>15)
                        app.ADVVCMConsultaMedico.Text=('La cuota moderadora no puede superar 15 dígitos');
                        A4=1;
                    else
                        app.ADVVCMConsultaMedico.Text=('');
                        A4=0;
                    end
                end
                % Consulta
                if ((isnan(app.ValorCConsultaMedico.Value))==0)
                    if (numel(num2str(app.ValorCConsultaMedico.Value))>15)
                        app.ADVVCConsultaMedico.Text=('El valor de consluta no puede superar 15 dígitos');
                        A5=1;
                    else
                        app.ADVVCConsultaMedico.Text=('');
                        A5=0;
                    end
                end
                %Neto
                if ((isnan(app.ValorNConsultaMedico.Value))==0)
                    if (numel(num2str(app.ValorNConsultaMedico.Value))>15)
                        app.ADVVNPConsultaMedico.Text=('El valor neto no puede superar 15 dígitos');
                        A6=1;
                    else
                        app.ADVVNPConsultaMedico.Text=('');
                        A6=0;
                    end
                end
                %Finalidad de la consulta
                if(app.FConsultaMedico.Value == "05")
                    if (Edad(1)<10 || Edad(1)>29)
                        app.ADVFCConsultaMedico.Text='La finalidad de consulta no es válida';
                        A7=1;
                    end
                else
                    app.ADVFCConsultaMedico.Text='';
                    A7=0;
                end
                app.CDPConsultaMedico.BackgroundColor=[1,1,1];
                %Envio de datos
                if((A0+A1+A2+A3+A4+A5+A6+A7)==0)
                    app.OkConsultaMedico.Value='Registrado';
                else
                    app.OkConsultaMedico.Value='Verifique información';
                end
            end
        end

        % Button pushed function: RestablecerConsultaMedico
        function RestablecerConsultaMedicoButtonPushed(app, event)
            app.CDPConsultaMedico.BackgroundColor=[1,1,1];
            app.FConsultaMedico.Value='--Seleccione--';
            app.CExternaMedico.Value='--Seleccione--';
            app.TDiagnosticoMedico.Value='--Seleccione--';
            app.NAutorizacionConsultaMedico.Value='';
            app.CDPConsultaMedico.Value='';
            app.CDR1Consultamedico.Value='';
            app.CDR2ConsultaMedico.Value='';
            app.CDR3ConsultaMedico.Value='';
            app.ValorCConsultaMedico.Value=0;
            app.ValorCMConsultaMedico.Value=0;
            app.ValorNConsultaMedico.Value=0;
            app.CConsultaMedico.Value='';
            app.OkConsultaMedico.Value='';
            app.ADVCDR1ConsutlaMedico.Text='';
            app.ADVCDR2ConsutlaMedico.Text='';
            app.ADVCDR3ConsutlaMedico.Text='';
            app.ADVVNPConsultaMedico.Text=('');
            app.ADVFCConsultaMedico.Text='';
            app.ADVCDPConsultaMedico.Text='';
            app.ADVVCConsultaMedico.Text='';
            app.ADVVCMConsultaMedico.Text='';
        end

        % Button pushed function: EnviarProcedimientoMedico
        function EnviarProcedimientoMedicoButtonPushed(app, event)
            app.OkProecedimientoMedico.Value = sprintf('Registrado');
        end

        % Button pushed function: RestablecerProcedimientoMedico
        function RestablecerProcedimientoMedicoButtonPushed(app, event)
            
            app.FPProcedimientoMedico.Value='--Seleccione--';
            app.PersonalAProcedimientoMedico.Value='--Seleccione--';
            app.APRProcedimientoMedico.Value='1';
            app.DPProcedimientoMedico.Value='';
            app.CProcedimientoProcedimientoMedico.Value='';
            app.ComplicacionProcedimientoMedico.Value='';
            app.FRAQProcedimientoMedico.Value='';
            app.NAutorizacionProcedimientoMedico.Value='';
            app.DRProcedimientoMedico.Value='';
            app.VNPProcedimientoMedico.Value=0;
            app.CIPSProcedimientoMedico.Value='';
            app.OkProecedimientoMedico.Value='';
            app.NFacturaProcedimientoMedico.Value='';
            
        end

        % Value changed function: PreviousButton
        function PreviousButtonValueChanged(app, event)
            global clic
            value = app.PreviousButton.Value;
            if (value ==1)
                clic=clic-1;
            end
            switch clic
                case 1
                    app.Indicacion.Text = {'Encienda los sensores oprimiendo el botón de cada uno por 5 segundos';'conecte el Awinda Station a su computador e inicie la aplicación Xsens Analyzer.'};
                    Paso1=imread("PrimerPaso.png");
                    imshow(Paso1, 'Parent', app.UIAxes);
                    app.PrimerPaso_2.Value = 0;
                case 2
                    app.Indicacion.Text = {'Ingrese la información antropométrica del participante dando clic en el botón azul'; 'Tome las medidas utilizando la cinta métrica dispuesta para ello.'};
                    Paso2=imread("Antropometria.png");
                    imshow(Paso2, 'Parent', app.UIAxes);
                    app.PrimerPaso_3.Value = 0;
                case 3
                    app.Indicacion.Text = {'Sujete los sensores al cuerpo del participante con los velcros o cintas médicas';' como se muestra a continuación.'};
                    Paso3=imread("SegundoPaso.png");
                    imshow(Paso3, 'Parent', app.UIAxes);
                    app.PrimerPaso_4.Value = 0;
                case 4
                    app.Indicacion.Text = sprintf('Calibre los sensores siguiendo el protocolo especificado en el software Xsens Analyze.');
                    Paso4=imread("Calibracion.png");
                    imshow(Paso4, 'Parent', app.UIAxes);
                    app.PrimerPaso_5.Value = 0;
                case 5
                    app.Indicacion.Text = sprintf('Indique al participante que iniciará la PEBI, registre toda la información en un solo archivo .mvnx.');
                    Paso5=imread("PEBIparticipante.jpeg");
                    imshow(Paso5, 'Parent', app.UIAxes);
                    app.PrimerPaso_6.Value = 0;
                case 6 %%FALTA FOTO DE EXPORTAR DATOS MVNX
                    app.Indicacion.Text = {'Exporte los datos en un archivo con extensión .mvnx, asegúrese de seleccionar todas las variables'};
                    Paso6=imread("PEBIparticipante.jpeg");
                    imshow(Paso6, 'Parent', app.UIAxes);
                    app.PrimerPaso_7.Value = 0;
                case 7
                    app.Indicacion.Text = {'Retire los sensores del cuerpo del participante y apáguelos,' ; 'de ser necesario acóplelos al Awinda Station para cargarlos.'};
                    Paso7=imread("SensoresAcoplados.jpg");
                    imshow(Paso7, 'Parent', app.UIAxes);
                    app.PrimerPaso_8.Value = 0;
                case 8
                    app.Indicacion.Text = sprintf('En el sistema de visualización ASPEBI, cargue el archivo en la ventana de reproducción.');
                    Paso8=imread("Importar.png");
                    imshow(Paso8, 'Parent', app.UIAxes);
                    app.PrimerPaso_9.Value = 0;
                case 9
                    app.Indicacion.Text = sprintf('Inicie la reproducción del movimiento con el botón "Iniciar".');
                    Paso9=imread("Movimiento.png");
                    imshow(Paso9, 'Parent', app.UIAxes);
                    app.PrimerPaso_9.Value = 0;
            end
        end

        % Value changed function: NextButton
        function NextButtonValueChanged(app, event)
            global clic
            value = app.NextButton.Value;
            if (value ==1)
                clic=clic+1;
            end
            switch clic
                case 1
                    app.Indicacion.Text = {'Turn on the sensors by pressing the button on each sensor for 5 seconds';'connect the Awinda Station to your computer and start the Xsens Analyzer application'};
                    Paso1=imread("PrimerPaso.png");
                    imshow(Paso1, 'Parent', app.UIAxes);
                    app.PrimerPaso.Value = 1;
                case 2
                    app.Indicacion.Text = {'Ingrese la información antropométrica del participante dando clic en el botón azul'; 'Tome las medidas utilizando la cinta métrica dispuesta para ello.'};
                    Paso2=imread("Antropometria.png");
                    imshow(Paso2, 'Parent', app.UIAxes);
                    app.PrimerPaso_2.Value = 1;
                case 3
                    app.Indicacion.Text = {'Sujete los sensores al cuerpo del participante con los velcros o cintas médicas';' como se muestra a continuación.'};
                    Paso3=imread("SegundoPaso.png");
                    imshow(Paso3, 'Parent', app.UIAxes);
                    app.PrimerPaso_3.Value = 1;
                case 4
                    app.Indicacion.Text = sprintf('Calibre los sensores siguiendo el protocolo especificado en el software Xsens Analyze.');
                    Paso4=imread("Calibracion.png");
                    imshow(Paso4, 'Parent', app.UIAxes);
                    app.PrimerPaso_4.Value = 1;
                case 5
                    app.Indicacion.Text = sprintf('Indique al participante que iniciará la PEBI, registre toda la información en un solo archivo .mvnx.');
                    Paso5=imread("PEBIparticipante.jpeg");
                    imshow(Paso5, 'Parent', app.UIAxes);
                    app.PrimerPaso_5.Value = 1;
                case 6 %%FALTA FOTO DE EXPORTAR DATOS MVNX
                    app.Indicacion.Text = {'Exporte los datos en un archivo con extensión .mvnx, asegúrese de seleccionar todas las variables' ; 'como se ilustra a continuación.'};
                    Paso6=imread("PEBIparticipante.jpeg");
                    imshow(Paso6, 'Parent', app.UIAxes);
                    app.PrimerPaso_6.Value = 1;
                case 7
                    app.Indicacion.Text = {'Retire los sensores del cuerpo del participante y apáguelos,' ; 'de ser necesario acóplelos al Awinda Station para cargarlos.'};
                    Paso7=imread("SensoresAcoplados.jpg");
                    imshow(Paso7, 'Parent', app.UIAxes);
                    app.PrimerPaso_7.Value = 1;
                case 8
                    app.Indicacion.Text = sprintf('En el sistema de visualización ASPEBI, cargue el archivo en la ventana de reproducción.');
                    Paso8=imread("Importar.png");
                    imshow(Paso8, 'Parent', app.UIAxes);
                    app.PrimerPaso_8.Value = 1;
                case 9
                    app.Indicacion.Text = sprintf('Inicie la reproducción del movimiento con el botón "Iniciar".');
                    Paso9=imread("Movimiento.png");
                    imshow(Paso9, 'Parent', app.UIAxes);
                    app.PrimerPaso_9.Value = 1;
            end
        end

        % Button pushed function: CalcularEspaciotemporales
        function CalcularEspaciotemporalesPushed(app, event)
            global Datos_segmentos sesion PIESB
            Espaciotemporales=[sesion.footContact(1).footContacts,sesion.footContact(2).footContacts,sesion.footContact(3).footContacts,sesion.footContact(4).footContacts];
            PIES=[num2str(Espaciotemporales(:,1)),num2str(Espaciotemporales(:,2)),num2str(Espaciotemporales(:,3)),num2str(Espaciotemporales(:,4))];
            PIES=str2num(PIES); %#ok<ST2NM>
            
            %PARTIR--------------------------------------------------------------------
            [partir111]=find(PIES==1111);
            partir11= [partir111;0]-[0;partir111];
            partir1=find(partir11~=1);
            framebipedo=partir111([partir1(1:end-1);(partir1(end)-1)]);
            ronald1=[0;(diff(framebipedo)>400)];
            ronald11=find(ronald1==1);
            ronald111=ronald11(1);
            %ANTES---------------------------------------------------------------------
            m1=framebipedo(ronald111);
            PIESA=PIES(1:m1);
            %DESPUES-------------------------------------------------------------------
            ronald22=find(ronald1==1);
            ronald222=ronald22(end)-1;
            ronald2222=framebipedo(ronald222);
            PIESB=PIES(ronald2222:end);
            
            %% --------------------PARAMETROS ANTES-------------------------------------
            %Detección de largo de paso por contacto inicial
            [xpas111]=find(PIESA==1001); %pie izquierdo
            [xpas222]=find(PIESA==0110); %pie derecho
            xpas11= [xpas111;0]-[0;xpas111];
            xpas22= [xpas222;0]-[0;xpas222];
            xpas1=find(xpas11~=1);
            xpas2=find(xpas22~=1);
            framexpasIzq=xpas111([xpas1(1:end-1);(xpas1(end)-1)]);
            framexpasDer=xpas222([xpas2(1:end-1);(xpas2(end)-1)]);
            %%
            %Detección de balanceo inicial 
            [xba111]=find(PIESA==0001);
            xba11=[xba111;0]-[0;xba111];
            xba1=find(xba11~=1);
            frameBalanceoIzq=xba111([xba1(1:end-1);(xba1(end)-1)]);
            %% Parámetros espaciotemporales 
            %Tiempo de marcha 
            TMarcha=length(PIESA)/str2double(sesion.metaData.subject_frameRate);
            %Largo de zancada 
            PosPasosIzq=Datos_segmentos(22).position(framexpasIzq,1);  
            ZancadaIzq = abs(diff(abs(PosPasosIzq)));
            LzancadaIzq = ZancadaIzq(3);
            app.LeftstridelengthEditField.Value = LzancadaIzq;
            PosPasosDer = Datos_segmentos(18).position(framexpasDer,1);
            ZancadaDer = abs(diff(abs(PosPasosDer)));
            LzancadaDer = median(ZancadaDer);
            app.RigthstridelengthEditField.Value = LzancadaDer;
            %Largo de paso 
            if (length(PosPasosIzq)==length(PosPasosDer))
                
            LargoPaso = abs(abs(PosPasosIzq)-abs(PosPasosDer));
            Lpaso = median(LargoPaso); 
            
            PasoDer = Datos_segmentos(18).position(framexpasDer,2);
            PasoIzq = Datos_segmentos(22).position(framexpasIzq,2);
            AnchoPaso = median (abs(abs(PasoDer)-abs(PasoIzq)));
            else 
                paula=length(PosPasosIzq)<length(PosPasosDer);
                if paula ==1
                %Largo de paso 
                LargoPaso = abs(abs(PosPasosIzq)-abs(PosPasosDer(1:length(PosPasosIzq))));
                Lpaso = median(LargoPaso); 
                
                %Ancho de paso 
                PasoDer = Datos_segmentos(18).position(framexpasDer,2);
                PasoIzq = Datos_segmentos(22).position(framexpasIzq,2);
                AnchoPaso = median (abs(abs(PasoDer(1:length(PosPasosIzq)))-abs(PasoIzq)));
                else
                    
                LargoPaso = abs(abs(PosPasosIzq(1:length(PosPasosDer)))-abs(PosPasosDer));
                Lpaso = median(LargoPaso); 
            
                %Ancho de paso 
                PasoDer = Datos_segmentos(18).position(framexpasDer,2);
                PasoIzq = Datos_segmentos(22).position(framexpasIzq,2);
                AnchoPaso = median (abs(abs(PasoDer)-abs(PasoIzq(1:length(PosPasosDer)))));
                end
            end
            
             app.SteplengthEditField.Value = Lpaso;
             app.StepwidthEditField.Value = AnchoPaso;
            
            %Cadencia 
            NumPasos = length(framexpasIzq) + length(framexpasDer);
            Cadencia =(NumPasos*60)/TMarcha;
            app.CadenceEditField.Value = Cadencia;
            %Velocidad 
            Velocidad=max(abs(sesion.segmentData(1).position(:,1)))/TMarcha;
            app.Velocidaddemarcha.Value = Velocidad;
            
            %% ----------------------------PARAMETROS DESPUES---------------------------
            %Detección de largo de paso por contacto inicial
            [xpas111B]=find(PIESB==1001); %pie izquierdo
            [xpas222B]=find(PIESB==0110); %pie derecho
            xpas11B= [xpas111B;0]-[0;xpas111B];
            xpas22B= [xpas222B;0]-[0;xpas222B];
            xpas1B=find(xpas11B~=1);
            xpas2B=find(xpas22B~=1);
            framexpasIzqB=xpas111B([xpas1B(1:end-1);(xpas1B(end)-1)]);
            framexpasDerB=xpas222B([xpas2B(1:end-1);(xpas2B(end)-1)]);
            %%
            %Detección de balanceo inicial 
            [xba111B]=find(PIESB==0001);
            xba11B=[xba111B;0]-[0;xba111B];
            xba1B=find(xba11B~=1);
            frameBalanceoIzqB=xba111B([xba1B(1:end-1);(xba1B(end)-1)]);
            %% Parámetros espaciotemporales 
            %Tiempo de marcha 
            TMarchaB=length(PIESB)/str2double(sesion.metaData.subject_frameRate);
            %Largo de zancada 
            PosPasosIzqB=Datos_segmentos(22).position(framexpasIzqB,1);  
            ZancadaIzqB = abs(diff(abs(PosPasosIzqB)));
            LzancadaIzqB = ZancadaIzqB(3);
            PosPasosDerB = Datos_segmentos(18).position(framexpasDerB,1);
            ZancadaDerB = abs(diff(abs(PosPasosDerB)));
            LzancadaDerB = median(ZancadaDerB);
            %Largo de paso 
            if (length(PosPasosIzqB)==length(PosPasosDerB))
                
            LargoPasoB = abs(abs(PosPasosIzqB)-abs(PosPasosDerB));
            LpasoB = median(LargoPasoB); 
            
            PasoDerB = Datos_segmentos(18).position(framexpasDerB,2);
            PasoIzqB = Datos_segmentos(22).position(framexpasIzqB,2);
            AnchoPasoB = median (abs(abs(PasoDerB)-abs(PasoIzqB)));
            else 
                paulaB=length(PosPasosIzqB)<length(PosPasosDerB);
                if paulaB ==1
                %Largo de paso 
                LargoPasoB = abs(abs(PosPasosIzqB)-abs(PosPasosDerB(1:length(PosPasosIzqB))));
                LpasoB = median(LargoPasoB); 
                
                %Ancho de paso 
                PasoDerB = Datos_segmentos(18).position(framexpasDerB,2);
                PasoIzqB = Datos_segmentos(22).position(framexpasIzqB,2);
                AnchoPasoB = median (abs(abs(PasoDerB(1:length(PosPasosIzqB)))-abs(PasoIzqB)));
                else
                    
                LargoPasoB = abs(abs(PosPasosIzqB(1:length(PosPasosDerB)))-abs(PosPasosDerB));
                LpasoB = median(LargoPasoB); 
            
                %Ancho de paso 
                PasoDerB = Datos_segmentos(18).position(framexpasDerB,2);
                PasoIzqB = Datos_segmentos(22).position(framexpasIzqB,2);
                AnchoPasoB = median (abs(abs(PasoDerB)-abs(PasoIzqB(1:length(PosPasosDerB)))));
                end
            end
            %Cadencia 
            NumPasosB = length(framexpasIzqB) + length(framexpasDerB);
            CadenciaB =(NumPasosB*60)/TMarchaB;
            %Velocidad 
            VelocidadB=max(abs(sesion.segmentData(1).position(:,1)))/TMarchaB;

            
%             
%             %Detección de largo de paso por contacto inicial
%             [xpas111]=find(PIES==1001); %pie izquierdo
%             [xpas222]=find(PIES==0110); %pie derecho
%             xpas11= [xpas111;0]-[0;xpas111];
%             xpas22= [xpas222;0]-[0;xpas222];
%             xpas1=find(xpas11~=1);
%             xpas2=find(xpas22~=1);
%             framexpasIzq=xpas111([xpas1(1:end-1);(xpas1(end)-1)]);
%             framexpasDer=xpas222([xpas2(1:end-1);(xpas2(end)-1)]);
%             
%             %% Parámetros espaciotemporales
%             %Tiempo de marcha
%             TMarcha=length(PIES)/str2double(sesion.metaData.subject_frameRate);
%             
%             %Largo de zancada
%             PosPasosIzq=Datos_segmentos(22).position(framexpasIzq,1);
%             ZancadaIzq = abs(diff(abs(PosPasosIzq)));
%             LzancadaIzq = ZancadaIzq(3);
%             app.LeftstridelengthEditField.Value = LzancadaIzq;
%             
%             PosPasosDer = Datos_segmentos(18).position(framexpasDer,1);
%             ZancadaDer = abs(diff(abs(PosPasosDer)));
%             LzancadaDer = median(ZancadaDer);
%             app.RigthstridelengthEditField.Value = LzancadaDer;
%             
%             %Largo de paso
%             if (length(PosPasosIzq)==length(PosPasosDer))
%     
%             LargoPaso = abs(abs(PosPasosIzq)-abs(PosPasosDer));
%             Lpaso = median(LargoPaso); 
%             
%             PasoDer = Datos_segmentos(18).position(framexpasDer,2);
%             PasoIzq = Datos_segmentos(22).position(framexpasIzq,2);
%             AnchoPaso = median (abs(abs(PasoDer)-abs(PasoIzq)));
%             else 
%                 paula=length(PosPasosIzq)<length(PosPasosDer);
%                 if paula ==1
%                 %Largo de paso 
%                 LargoPaso = abs(abs(PosPasosIzq)-abs(PosPasosDer(1:length(PosPasosIzq))));
%                 Lpaso = median(LargoPaso); 
%                 
%                 %Ancho de paso 
%                 PasoDer = Datos_segmentos(18).position(framexpasDer,2);
%                 PasoIzq = Datos_segmentos(22).position(framexpasIzq,2);
%                 AnchoPaso = median (abs(abs(PasoDer(1:length(PosPasosIzq)))-abs(PasoIzq)));
%                 else
%                     
%                 LargoPaso = abs(abs(PosPasosIzq(1:length(PosPasosDer)))-abs(PosPasosDer));
%                 Lpaso = median(LargoPaso); 
%             
%                 %Ancho de paso 
%                 PasoDer = Datos_segmentos(18).position(framexpasDer,2);
%                 PasoIzq = Datos_segmentos(22).position(framexpasIzq,2);
%                 AnchoPaso = median (abs(abs(PasoDer)-abs(PasoIzq(1:length(PosPasosDer)))));
%                 end
%             end
%             app.SteplengthEditField.Value = Lpaso;
%             app.StepwidthEditField.Value = AnchoPaso;
%             
%             %Cadencia
%             NumPasos = length(framexpasIzq) + length(framexpasDer);
%             Cadencia =(NumPasos*60)/TMarcha;
%             app.CadenceEditField.Value = Cadencia;
%             
%             %Velocidad
%             Velocidad=max(abs(sesion.segmentData(1).position(:,1)))/TMarcha;
%             app.Velocidaddemarcha.Value = Velocidad;
            
        end

        % Value changed function: Angulosgraficas_2
        function Angulosgraficas_2ValueChanged(app, event)
            global  Datos_articulaciones frec;
            
            ACaderaD=Datos_articulaciones(15).jointAngle;
            ACaderaI=Datos_articulaciones(19).jointAngle;
            ARodillaD=Datos_articulaciones(16).jointAngle;
            ARodillaI=Datos_articulaciones(20).jointAngle;
            ATobilloD=Datos_articulaciones(17).jointAngle;
            ATobilloI=Datos_articulaciones(21).jointAngle;
            EJEX=(0:(1/frec):(length(ACaderaI)*1/frec - 1/frec));
            
            Angulo = app.Angulosgraficas_2.Value;
            
            switch Angulo
                case "Flexión/Extensión Cadera"
                    plot(app.Axesgrafica,EJEX,ACaderaD(:,3),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ACaderaI(:,3),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Aducción/Abducción Cadera"
                    plot(app.Axesgrafica,EJEX,ACaderaD(:,1),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ACaderaI(:,1),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Rotación Cadera"
                    plot(app.Axesgrafica,EJEX,ACaderaD(:,2),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ACaderaI(:,2),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Flexión/Extensión Rodilla"
                    plot(app.Axesgrafica,EJEX,ARodillaD(:,3),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ARodillaI(:,3),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Aducción/Abducción Rodilla"
                    plot(app.Axesgrafica,EJEX,ARodillaD(:,1),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ARodillaI(:,1),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Rotación Rodilla"
                    plot(app.Axesgrafica,EJEX,ARodillaD(:,2),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ARodillaI(:,2),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Dorsiflexión/Plantiflexión Tobillo"
                    plot(app.Axesgrafica,EJEX,ATobilloD(:,3),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ATobilloI(:,3),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Rotación Tobillo"
                    plot(app.Axesgrafica,EJEX,ATobilloD(:,2),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ATobilloI(:,2),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
                case "Aducción/Abducción Tobillo"
                    plot(app.Axesgrafica,EJEX,ATobilloD(:,1),"Color",'r');
                    hold (app.Axesgrafica, 'on')
                    plot(app.Axesgrafica,EJEX,ATobilloI(:,1),"Color",'b');
                    hold (app.Axesgrafica, 'off')
                    legend(app.Axesgrafica,{'Right','Left'})
            end
            
            
        end

        % Selection changed function: TypeoffileButtonGroup
        function TypeoffileButtonGroupSelectionChanged(app, event)
            BotonImportipo = app.TypeoffileButtonGroup.SelectedObject;
            
            switch  BotonImportipo.Text
                case 'PEBI'
                    app.Angulosgraficas_2.Items=["Seleccionar","Flexión/Extensión Cadera",...
                        "Aducción/Abducción Cadera","Rotación Cadera","Flexión/Extensión Rodilla"...
                        "Rotación Rodilla","Dorsiflexión/Plantiflexión Tobillo","Rotación Tobillo" ];
                    app.RotacinTobillo.Enable='on';
                    app.RotacinRodilla.Enable='on';
                    app.FlexinExtensinRodilla.Enable='on';
                    app.DorsiflexinPlantiflexinTobillo.Enable='on';
                    app.AduccinAbduccinTobillo.Enable='on';
                    app.AduccinAbduccinRodilla.Enable='on';
                    app.VisualizarInforme.Enable='on';
                    
                case 'Variables'
                    app.Angulosgraficas_2.Items=["Seleccionar","Flexión/Extensión Cadera","Aducción/Abducción Cadera","Rotación Cadera"];
                    app.RotacinTobillo.Enable='off';app.RotacinTobillo.Value=0;
                    app.RotacinRodilla.Enable='off';app.RotacinRodilla.Value=0;
                    app.FlexinExtensinRodilla.Enable='off';app.FlexinExtensinRodilla.Value=0;
                    app.DorsiflexinPlantiflexinTobillo.Enable='off';app.DorsiflexinPlantiflexinTobillo.Value=0;
                    app.AduccinAbduccinRodilla.Enable='off';app.AduccinAbduccinRodilla.Value=0;
                    app.AduccinAbduccinTobillo.Enable='off';app.AduccinAbduccinTobillo.Value=0;
                    app.CalcularEspaciotemporales.Enable='off';
                    app.CalcularEspaciotemporales_2.Enable='off';
                    app.VisualizarInforme.Enable='off';
            end
            
            
        end

        % Button pushed function: Ampliar
        function AmpliarButtonPushed(app, event)
            
            global  Datos_articulaciones frec;
            
            ACaderaD=Datos_articulaciones(15).jointAngle;
            ACaderaI=Datos_articulaciones(19).jointAngle;
            ARodillaD=Datos_articulaciones(16).jointAngle;
            ARodillaI=Datos_articulaciones(20).jointAngle;
            ATobilloD=Datos_articulaciones(17).jointAngle;
            ATobilloI=Datos_articulaciones(21).jointAngle;
            EJEX=(0:(1/frec):(length(ACaderaI)*1/frec - 1/frec));
            
            valRC=app.RotacinCadera.Value;
            valFEC=app.FlexinExtensinCadera.Value;
            valADC=app.AduccinAbduccinCadera.Value;
            valRR=app.RotacinRodilla.Value;
            valFER= app.FlexinExtensinRodilla.Value;
            valRT=app.RotacinTobillo.Value;
            valDPT=app.DorsiflexinPlantiflexinTobillo.Value;
            valADR=app.AduccinAbduccinRodilla.Value;
            valADT=app.AduccinAbduccinTobillo.Value;
            
            if valADC==1
                figure
                plot(EJEX,ACaderaD(:,1),"Color",'r');
                hold on
                plot(EJEX,ACaderaI(:,1),"Color",'b');
                title('Aducción/Abducción Cadera')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valRC==1
                figure
                plot(EJEX,ACaderaD(:,2),"Color",'r');
                hold on
                plot(EJEX,ACaderaI(:,2),"Color",'b');
                title('Rotación Cadera')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valFEC==1
                figure
                plot(EJEX,ACaderaD(:,3),"Color",'r');
                hold on
                plot(EJEX,ACaderaI(:,3),"Color",'b');
                title('Flexión/Extensión Cadera')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valADR==1
                figure
                plot(EJEX,ARodillaD(:,1),"Color",'r');
                hold on
                plot(EJEX,ARodillaI(:,1),"Color",'b');
                title('Aducción/Abducción Rodilla')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valRR==1
                figure
                plot(EJEX,ARodillaD(:,2),"Color",'r');
                hold on
                plot(EJEX,ARodillaI(:,2),"Color",'b');
                title('Rotación Rodilla')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valFER==1
                figure
                plot(EJEX,ARodillaD(:,3),"Color",'r');
                hold on
                plot(EJEX,ARodillaI(:,3),"Color",'b');
                title('Flexión/Extensión Rodilla')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valADT==1
                figure
                plot(EJEX,ATobilloD(:,1),"Color",'r');
                hold on
                plot(EJEX,ATobilloI(:,1),"Color",'b');
                title('Aducción/Abducción Tobillo')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valRT==1
                figure
                plot(EJEX,ATobilloD(:,2),"Color",'r');
                hold on
                plot(EJEX,ATobilloI(:,2),"Color",'b');
                title('Rotación Tobillo')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
            if valDPT==1
                figure
                plot(EJEX,ATobilloD(:,3),"Color",'r');
                hold on
                plot(EJEX,ATobilloI(:,3),"Color",'b');
                title('Dorsiflexión/Plantiflexión Tobillo')
                xlabel('Tiempo(s)')
                ylabel('Grados(º)')
                hold off
                legend({'Derecha','Izquierda'})
            end
             
            
        end

        % Button pushed function: VisualizarInforme
        function VisualizarInformeButtonPushed(app, event)
        global LzancadaIzqR LzancadaDer Lpaso AnchoPaso CadenciaR VelocidadR FCaderaDer RCaderaDer AbCaderaDer CaderaDer FCaderaIzq RCaderaIzq AbCaderaIzq
        global CaderaIzq FTobilloDer RTobilloDer AbTobilloDer TobilloDer FTobilloIzq RTobilloIzq AbTobilloIzq TobilloIzq
        global FRodillaDer RRodillaDer AbRodillaDer RodillaDer FRodillaIzq RRodillaIzq AbRodillaIzq RodillaIzq  
        
        import mlreportgen.dom.*;
        import mlreportgen.dom.Table.*;
        import mlreportgen.report.*;
        
        if app.PEBIMedico.Value == 1
            PEBI='Negative';
        elseif app.PEBIInvestigador.Value == 1
            PEBI='Negative';
        else
            PEBI='Positive';
        end
               
        Reporte = Report('PEBI Report', 'pdf');
        open(Reporte)

        
        tp = TitlePage();
        tp.Subtitle = 'Gait spatiotemporal variables analysis';
        tp.Author = 'ASPEBI';
        Espacio = strcat({' '});
        InfoR = Paragraph('Patients information');
        InfoR.Style = {Bold};
        
        if app.OkInvestigador.Value == "Information registered"
        NombreR = strcat('Name:',{' '}, app.PNInvestigador.Value, {' '},  app.SNInvestigador.Value, {' '}, app.PAInvestigador.Value, {' '}, app.SAInvestigador.Value);
        SexoR = strcat('Gender:',{' '},app.SexoInvestigador.Value);
        EdadR = strcat('Age:',{' '}, app.EdadInvestigador.Value);
        PEBIR = strcat('PEBI:',{' '}, PEBI);
        elseif((app.OkParticipanteMedico.Value=="Registrado")&&(app.OkConsultaMedico.Value=="Registrado")&&(app.OkProecedimientoMedico.Value=="Registrado"))
        NombreR = strcat('Name:',{' '}, app.PNMedico.Value, {' '},  app.SNMedico.Value, {' '}, app.PAMedico.Value, {' '}, app.SAMedico.Value);
        SexoR = strcat('Gender:',{' '},app.SexoMedico.Value);
        EdadR = strcat('Age:',{' '}, app.EdadMedico.Value);
        PEBIR = strcat('PEBI:',{' '}, PEBI);
        else
        NombreR = strcat('Name:',{' '});
        SexoR = strcat('Gender:',{' '});
        EdadR = strcat('Age:',{' '});
        PEBIR = strcat('PEBI:',{' '});    
        end
        
        
        %% Agregar Pebi positiva o negativa 
        
        
        LzancadaIzqR=num2str(LzancadaIzqR);
        LzancadaDer = num2str(LzancadaDer);
        Lpaso = num2str(Lpaso);
        AnchoPaso = num2str(AnchoPaso);
        CadenciaR = num2str(CadenciaR);
        VelocidadR = num2str(VelocidadR);
        
        TablaParR = Paragraph('Table 1. Spatiotemporal parameters of gait');
        TablaParR.Style = {Bold};
        
        tableStyle = {Width('100%'), Border('solid'), ColSep('solid'), RowSep('solid')};
        mainHeaderRowStyle = {VAlign('middle'), InnerMargin('2pt', '2pt', '2pt', '2pt'), ...
        BackgroundColor('cadetblue')};
        mainHeaderTextStyle = {Bold, OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Arial')};
        subHeaderRowStyle = {VAlign('middle'), InnerMargin('2pt', '2pt', '2pt', '2pt'), BackgroundColor('azure')};
        subHeaderTextStyle = {Bold, OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Arial')};
        bodyStyle = {OuterMargin('0pt', '0pt', '0pt', '0pt'), InnerMargin('2pt', '2pt', '2pt', '0pt')};
                
                
        data = {'Left stride length', LzancadaIzqR, '1.007 ± .083'; ...
                           'Right stride length' LzancadaDer '1.07 ± .074'; ...
                           'Step length' Lpaso '0.545 ± .035'; ...
                           'Step width ' AnchoPaso '0.079 ± .013'; ...
                           'Cadence' CadenciaR '128.483 ± 14.925' ; ...
                           'Gait speed' VelocidadR '1.114 ± .205'};
        
        t = Table(3);
        t.Style = [t.Style tableStyle];
        
        r = TableRow;
        r.Style = [r.Style mainHeaderRowStyle];
        p = Paragraph('Gait parameters');
        p.Style = [p.Style mainHeaderTextStyle {HAlign('center')}];
        te = TableEntry(p);
        te.ColSpan = 2;
        append(r, te);
        
        p = Paragraph('Average values');
        p.Style = [p.Style mainHeaderTextStyle];
        te = TableEntry(p);
        te.RowSpan = 2;
        append(r, te);
        append(t, r);
        
        r = TableRow;
        r.Style = [r.Style subHeaderRowStyle];
        p = Paragraph('Variable');
        p.Style = [p.Style subHeaderTextStyle];
        te = TableEntry(p);
        append(r, te);
        
        p = Paragraph('Result');
        p.Style = [p.Style subHeaderTextStyle];
        te = TableEntry(p);
        append(r, te);
        append(t, r);
        
        for k = 1:size(data, 1)
            r = TableRow;
            r.Style = [r.Style bodyStyle];
            te = TableEntry(data{k,1});
            append(r, te);
            te = TableEntry(data{k,2});
            append(r, te);
            te = TableEntry(data{k,3});
            append(r, te);
            append(t, r);
        end
        
        
        Espacio2 = strcat({' '});
        GraficasR = Paragraph('Tabla 2. Gait graphs');
        GraficasR.Style = {Bold};
        
        import mlreportgen.dom.*;
        import mlreportgen.dom.Table.*;
        import mlreportgen.report.*;
        
        imgStyle = {ScaleToFit(true)};

        fig1 = Figure(surf(CaderaDer,FCaderaDer));
        hold on 
        plot(CaderaIzq, FCaderaIzq);
        legend('Rigth','Left','');
        xline(60)
        title('Flexión/Extensión Cadera')
        fig1Img = Image(getSnapshotImage(fig1, Reporte));
        fig1Img.Style = imgStyle;
        delete(gcf);
        
        fig2 = Figure();
        plot(TobilloDer,FTobilloDer);
        hold on
        plot(TobilloIzq, FTobilloIzq);
        legend('Rigth','Left','');
        xline(60)
        title('\fontsize{27}Flexión/Extensión Tobillo')
        fig2Img = Image(getSnapshotImage(fig2, Reporte));
        fig2Img.Style = imgStyle;
       % delete(gcf);
        
        fig3 = Figure();
        plot(RodillaDer,FRodillaDer);
        hold on
        plot(RodillaIzq, FRodillaIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Flexión/Extensión Rodilla')
        fig3Img = Image(getSnapshotImage(fig3, Reporte));
        fig3Img.Style = imgStyle;
        delete(gcf);
        
        fig4 = Figure();
        plot(CaderaDer,AbCaderaDer);
        hold on
        plot(CaderaIzq, AbCaderaIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Aducción/Abducción Cadera')
        fig4Img = Image(getSnapshotImage(fig4, Reporte));
        fig4Img.Style = imgStyle;
        delete(gcf);
        
        fig5 = Figure();
        plot(TobilloDer,AbTobilloDer);
        hold on 
        plot(TobilloIzq, AbTobilloIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Aducción/Abducción Tobillo')
        fig5Img = Image(getSnapshotImage(fig5, Reporte));
        fig5Img.Style = imgStyle;
        delete(gcf);
        
        fig6 = Figure();
        plot(RodillaDer,AbRodillaDer);
        hold on 
        plot(RodillaIzq, AbRodillaIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Aducción/Abducción Rodilla')
        fig6Img = Image(getSnapshotImage(fig6, Reporte));
        fig6Img.Style = imgStyle;
        delete(gcf);
        
        fig7 = Figure();
        plot(CaderaDer,RCaderaDer);
        hold on
        plot (CaderaIzq, RCaderaIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Rotación Cadera')
        fig7Img = Image(getSnapshotImage(fig7, Reporte));
        fig7Img.Style = imgStyle;
        delete(gcf);
        
        fig8 = Figure();
        plot(TobilloDer,RTobilloDer);
        hold on
        plot(TobilloIzq, RTobilloIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Rotación Tobillo')
        fig8Img = Image(getSnapshotImage(fig8, Reporte));
        fig8Img.Style = imgStyle;
        delete(gcf);
        
        fig9 = Figure();
        plot(RodillaDer,RRodillaDer);
        hold on
        plot(RodillaIzq, RRodillaIzq);
        legend('Rigth','Left','')
        xline(60)
        title('\fontsize{27}Rotación Tobillo')
        fig9Img = Image(getSnapshotImage(fig9, Reporte));
        fig9Img.Style = imgStyle;
        delete(gcf);
     
               
        lo_table = Table({fig1, ' ', fig2Img, ' ',fig3Img; ...
                          fig4Img, ' ', fig5Img, ' ', fig6Img; ...
                          fig7Img, ' ', fig8Img, ' ', fig9Img});
        lo_table.entry(1,1).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(1,2).Style = {Width('0.01in'), Height('2.3in')};
        lo_table.entry(1,3).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(1,4).Style = {Width('0.01in'), Height('2.3in')};
        lo_table.entry(1,5).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(2,1).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(2,2).Style = {Width('0.01in'), Height('2.3in')};
        lo_table.entry(2,3).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(2,4).Style = {Width('0.01in'), Height('2.3in')};
        lo_table.entry(2,5).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(3,1).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(3,2).Style = {Width('0.01in'), Height('2.3in')};
        lo_table.entry(3,3).Style = {Width('2.3in'), Height('2.3in')};
        lo_table.entry(3,4).Style = {Width('0.01in'), Height('2.3in')};
        lo_table.entry(3,5).Style = {Width('2.3in'), Height('2.3in')};
                       
        add(Reporte,tp)
        add(Reporte,Espacio)
        add(Reporte,InfoR)
        add(Reporte, NombreR)
        add(Reporte, SexoR)
        add(Reporte,EdadR)
        add(Reporte,PEBIR)
        add(Reporte,Espacio)
        add(Reporte, TablaParR)
        add(Reporte,Espacio)
        append(Reporte, t);
        add(Reporte,Espacio2)
        add(Reporte,GraficasR);
        add(Reporte,lo_table)
        close(Reporte)
        rptview(Reporte);  
        end

        % Button pushed function: EnteranthropometricdataButton
        function EnteranthropometricdataButtonPushed(app, event)
            run DatosAntropometricos
        end

        % Value changed function: FNInvestigador
        function FNInvestigadorValueChanged(app, event)
            global EdadI FA
            format short g
            FNe = datevec(app.FNInvestigador.Value);
            
            FA=datevec(datetime('today'));
            
            EdadI=FA-FNe;
            
            app.EdadInvestigador.Value = num2str(EdadI(1));
            
        end

        % Button pushed function: Exportar
        function ExportarButtonPushed(app, event)
            global Datos_segmentos fullpathname FA RolInvestigador
            %Exportar información
            info=exportdata;
                        
            
            if sum(info.eleccion)>0
                
                    eleccion=info.eleccion;
                    ubicacion=info.ubicacion;
                    
                    export = uiprogressdlg(app.UIFigure,'Title','Exporting',...
                        'Message','Loading');
                    pause(.5)
           %-----------------------------------------Exportar Video-----------------------------------------------         
                    if eleccion(1,1)==1
                        %Video
                        vid = figure;
                        vid.Visible = 'off';
                        %Datos antropometricos
                        tobilloAl=0.085;
                        suela=0.01;
                        
                        %Posición Articulaciones
                        %L5
                        L5=Datos_segmentos(2).position;
                        %Pelvis
                        pelvis=Datos_segmentos(1).position;
                        %Articulacion Cadera-Muslo Derecha
                        ACMD=Datos_segmentos(16).position;
                        %Articulacion Cadera-Muslo Izquierda
                        ACMI=Datos_segmentos(20).position;
                        %Articulacion de la rodilla Derecha
                        ARD=Datos_segmentos(17).position;
                        %Articulacion de la rodilla Izquierda
                        ARI=Datos_segmentos(21).position;
                        %Articulacion del tobillo Derecho
                        ATD=Datos_segmentos(18).position;
                        %Articulacion del tobillo Izquierdo
                        ATI=Datos_segmentos(22).position;
                        %Dedo del pie Derecho
                        DedoD=Datos_segmentos(19).position;
                        %Dedo del pie Izquierdo
                        DedoI=Datos_segmentos(23).position;
                        %Talon del pie Derecho
                        TalonD=Datos_segmentos(18).position;
                        TalonD(:,3)=TalonD(:,3)-tobilloAl-suela;
                        %Talon del pie Derecho
                        TalonI=Datos_segmentos(22).position;
                        TalonI(:,3)=TalonI(:,3)-tobilloAl-suela;
                        %Segmentos
                        %l5
                        L5X=[L5(:,1),pelvis(:,1)];
                        L5Y=[L5(:,2),pelvis(:,2)];
                        L5Z=[L5(:,3),pelvis(:,3)];
                        %Cadera Derecha
                        CaderaXd=[pelvis(:,1),ACMD(:,1)];
                        CaderaYd=[pelvis(:,2),ACMD(:,2)];
                        CaderaZd=[pelvis(:,3),ACMD(:,3)];
                        %Cadera Izquierda
                        CaderaXi=[pelvis(:,1),ACMI(:,1)];
                        CaderaYi=[pelvis(:,2),ACMI(:,2)];
                        CaderaZi=[pelvis(:,3),ACMI(:,3)];
                        %Muslo Derecho
                        MusloXd=[ACMD(:,1),ARD(:,1)];
                        MusloYd=[ACMD(:,2),ARD(:,2)];
                        MusloZd=[ACMD(:,3),ARD(:,3)];
                        %Muslo Izquierda
                        MusloXi=[ACMI(:,1),ARI(:,1)];
                        MusloYi=[ACMI(:,2),ARI(:,2)];
                        MusloZi=[ACMI(:,3),ARI(:,3)];
                        %Pierna Derecha
                        PiernaXd=[ARD(:,1),ATD(:,1)];
                        PiernaYd=[ARD(:,2),ATD(:,2)];
                        PiernaZd=[ARD(:,3),ATD(:,3)];
                        %Pierna Izquierda
                        PiernaXi=[ARI(:,1),ATI(:,1)];
                        PiernaYi=[ARI(:,2),ATI(:,2)];
                        PiernaZi=[ARI(:,3),ATI(:,3)];
                        %Pie Derecho
                        PieXd=[ATD(:,1),DedoD(:,1)];
                        PieYd=[ATD(:,2),DedoD(:,2)];
                        PieZd=[ATD(:,3),DedoD(:,3)];
                        %Pie Derecho
                        PieXi=[ATI(:,1),DedoI(:,1)];
                        PieYi=[ATI(:,2),DedoI(:,2)];
                        PieZi=[ATI(:,3),DedoI(:,3)];
                        %Talon Derecho
                        TalXd=[ATD(:,1),TalonD(:,1)];
                        TalYd=[ATD(:,2),TalonD(:,2)];
                        TalZd=[ATD(:,3),TalonD(:,3)];
                        %Talon Izquierdo
                        TalXi=[ATI(:,1),TalonI(:,1)];
                        TalYi=[ATI(:,2),TalonI(:,2)];
                        TalZi=[ATI(:,3),TalonI(:,3)];
                        %Planta Derecha
                        PlaXd=[TalonD(:,1),DedoD(:,1)];
                        PlaYd=[TalonD(:,2),DedoD(:,2)];
                        PlaZd=[TalonD(:,3),DedoD(:,3)];
                        %Planta Izquierda
                        PlaXi=[TalonI(:,1),DedoI(:,1)];
                        PlaYi=[TalonI(:,2),DedoI(:,2)];
                        PlaZi=[TalonI(:,3),DedoI(:,3)];
                        
                        %Graficar
                        colorA=[0 0.4470 0.7410];
                        TA=4;
                        colorL=[0 0.6450 0.9330];
                        TL=5;
                        r=1;
                        for n=1:10:length(DedoI)
                            %Articulaciones-----------------------------------------------------------------------------
                            plot3(pelvis(n,1),pelvis(n,2),pelvis(n,3),'o','LineWidth' , 3,'color',colorA)
                            hold on
                            plot3(L5(n,1),L5(n,2),L5(n,3),'*','LineWidth' , TA,'color',colorA)
                            plot3(ACMD(n,1),ACMD(n,2),ACMD(n,3),'*','LineWidth' , TA,'color',colorA)
                            plot3(ACMI(n,1),ACMI(n,2),ACMI(n,3),'*','LineWidth' , TA,'color',colorA)
                            plot3(ARD(n,1),ARD(n,2),ARD(n,3),'*','LineWidth' , TA,'color',colorA)
                            plot3(ARI(n,1),ARI(n,2),ARI(n,3),'*','LineWidth' , TA,'color',colorA)
                            plot3(ATD(n,1),ATD(n,2),ATD(n,3),'*','LineWidth' , TA,'color',colorA)
                            plot3(ATI(n,1),ATI(n,2),ATI(n,3),'*','LineWidth' , TA,'color',colorA)
                            
                            plot3(DedoD(n,1),DedoD(n,2),DedoD(n,3),'*','LineWidth' , 2,'color',colorL)
                            plot3(DedoI(n,1),DedoI(n,2),DedoI(n,3),'*','LineWidth' , 2,'color',colorL)
                            plot3(TalonD(n,1),TalonD(n,2),TalonD(n,3),'*','LineWidth' , 2,'color',colorL)
                            plot3(TalonI(n,1),TalonI(n,2),TalonI(n,3),'*','LineWidth' , 2,'color',colorL)
                            
                            %Segmentos---------------------------------------------------------------------------------
                            %L5
                            plot3(L5X(n,:),L5Y(n,:),L5Z(n,:),'LineWidth' ,TL,'color',colorL)
                            %Cadera
                            plot3(CaderaXd(n,:),CaderaYd(n,:),CaderaZd(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(CaderaXi(n,:),CaderaYi(n,:),CaderaZi(n,:),'LineWidth' , TL,'color',colorL)
                            %Muslo
                            plot3(MusloXd(n,:),MusloYd(n,:),MusloZd(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(MusloXi(n,:),MusloYi(n,:),MusloZi(n,:),'LineWidth' , TL,'color',colorL)
                            %Pierna
                            plot3(PiernaXd(n,:),PiernaYd(n,:),PiernaZd(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(PiernaXi(n,:),PiernaYi(n,:),PiernaZi(n,:),'LineWidth' , TL,'color',colorL)
                            %Pie
                            plot3(PieXd(n,:),PieYd(n,:),PieZd(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(PieXi(n,:),PieYi(n,:),PieZi(n,:),'LineWidth' , TL,'color',colorL)
                            %Talon
                            plot3(TalXd(n,:),TalYd(n,:),TalZd(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(TalXi(n,:),TalYi(n,:),TalZi(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(PlaXd(n,:),PlaYd(n,:),PlaZd(n,:),'LineWidth' , TL,'color',colorL)
                            plot3(PlaXi(n,:),PlaYi(n,:),PlaZi(n,:),'LineWidth' , TL,'color',colorL)
                            
                            xlim([L5(n,1)-1 L5(n,1)+1]);ylim([L5(n,2)-1 L5(n,2)+1]);zlim([L5(n,3)-1.5 L5(n,3)+1]);
                            xlabel('X');ylabel('Y');zlabel('Z')
                            
                            campos([40,60,30])
                            
                            grid on
                            %axis equal
                            hold off
                            drawnow
                            
                            T(r)=getframe(gcf); %#ok<AGROW> 
                            r=r+1;
                        end
                        %HACER VIDEO
                        video=VideoWriter([ ubicacion '\video']);
                        video.Quality = 100;
                        video.FrameRate= 10;
                        open(video)
                        writeVideo(video,T)
                        close(video)
                    end
                    export.Value = .20;
                    export.Message = 'Seleccionando Información';
                    pause(1)
           %---------------------------------------- Registro Participante ------------------------------------------
                    if eleccion(2,1)==1
                        %Registro
                        %Registro Investigador
                        RolInvestigador=app.OkInvestigador.Value;
                        if app.OkInvestigador.Value == "Information registered"
                            
                            Archivo1=[ ubicacion '\Registro Participante.xls'];
                            
                            %Datos Generales
                            Parte1 = [{'Tipo de Documento:';'No. Documento:';'Primer Nombre:';...
                                'Segundo Nombre:';'Primer Apellido:';'Segundo Apellido:'}]; %#ok<NBRAK>
                            writecell(Parte1,Archivo1,'sheet','Datos Generales','Range','B2');
                            
                            Parte2 = [{app.TIDInvestigador.Value;app.IDInvestigador.Value;app.PNInvestigador.Value;...
                                app.SNInvestigador.Value;app.PAInvestigador.Value;app.SAInvestigador.Value}]; %#ok<NBRAK>
                            writecell(Parte2,Archivo1,'sheet','Datos Generales','Range','C2');
                            
                            Parte3 = [{'Sexo';'Zona de residencia:';'Fecha de nacimiento:';...
                                'Edad:';'Nivel de escolaridad:';'PEBI:'}]; %#ok<NBRAK>
                            writecell(Parte3,Archivo1,'sheet','Datos Generales','Range','D2');
                            
                            if app.PEBIInvestigador.Value == '-'
                                DPEBI='Negativo o No Diagnosticado';
                            else
                                DPEBI='Positivo';
                            end
                            Parte4 = [{app.SexoInvestigador.Value;app.ZRInvestigador.Value;app.FNInvestigador.Value;...
                                app.EdadInvestigador.Value;app.NivelmximodeescolaridadInvestigador.Value;DPEBI}]; %#ok<NBRAK> 
                            writecell(Parte4,Archivo1,'sheet','Datos Generales','Range','E2');
                        end
                        
                        %Registro Medico
                        if((app.OkParticipanteMedico.Value=="Registrado")&&(app.OkConsultaMedico.Value=="Registrado")&&(app.OkProecedimientoMedico.Value=="Registrado"))
                            mkdir([ubicacion '\Registro Medico'])
                            month=num2str(FA(2));
                            year=num2str(FA(1));
                            %CT
                            NombreCT=[ubicacion '\Registro Medico' strcat('\','CT',month,year,'.csv')];
                            CT={app.CIPSProcedimientoMedico.Value app.Fecha.Text NombreCT};
                            CTTable=cell2table(CT);
                            writetable(CTTable,NombreCT,'WriteVariableNames',0);
                            %US
                            US = {app.TIDMedico.Value app.IDMedico.Value app.CEAMedico.Value app.TUMedico.Value app.PAMedico.Value app.SAMedico.Value app.PNMedico.Value ...
                                app.SNMedico.Value app.EdadMedico.Value app.UEMedico.Value app.SexoMedico.Value app.CodDMedico.Value app.CodMMedico.Value app.ZRMedico.Value};
                            USTable=cell2table(US);
                            NombreUS=[ubicacion '\Registro Medico' strcat('\','US',month,year,'.csv')];
                            writetable(USTable,NombreUS,'WriteVariableNames',0);
                            %AC
                            AC = {app.TIDMedico.Value app.IDMedico.Value app.Fecha.Text app.NAutorizacionConsultaMedico.Value app.CConsultaMedico.Value app.FConsultaMedico.Value...
                                app.CExternaMedico.Value app.CDPConsultaMedico.Value app.CDR1Consultamedico.Value app.CDR2ConsultaMedico.Value app.CDR3ConsultaMedico.Value app.TDiagnosticoMedico.Value...
                                app.ValorCMConsultaMedico.Value app.ValorCMConsultaMedico.Value app.ValorNConsultaMedico.Value};
                            ACTable=cell2table(AC);
                            NombreAC=[ubicacion '\Registro Medico' strcat('\','AC',month,year,'.csv')];
                            writetable(ACTable,NombreAC,'WriteVariableNames',0);
                            %AP
                            AP = {app.NFacturaProcedimientoMedico.Value app.CIPSProcedimientoMedico.Value app.TIDMedico.Value app.IDMedico.Value app.Fecha.Text app.NAutorizacionProcedimientoMedico.Value...
                                app.CProcedimientoProcedimientoMedico.Value app.APRProcedimientoMedico.Value app.FPProcedimientoMedico.Value app.PersonalAProcedimientoMedico.Value app.DPProcedimientoMedico.Value...
                                app.DRProcedimientoMedico.Value app.ComplicacionProcedimientoMedico.Value app.FRAQProcedimientoMedico.Value app.VNPProcedimientoMedico.Value};
                            APTable=cell2table(AP);
                            NombreAP=[ubicacion '\Registro Medico' strcat('\','AP',month,year,'.csv')];
                            writetable(APTable,NombreAP,'WriteVariableNames',0);
                        else
                            disp('error no son correctos los datos de registro medico, verifique! ') 
                        end
                    end
                    
                    export.Value = .40;
                    export.Message = 'Seleccionando Información';
                    pause(1)
           %-------------------------------------------Informe de Marcha----------------------------------------------------------         
                    if eleccion(3,1)==1
                        %Informe de Marcha
                    end
                    
                    export.Value = .60;
                    export.Message = 'Seleccionando Información';
                    pause(1)
           %------------------------------------------- Información de los sensores ----------------------------------------------         
                    if eleccion(4,1)==1
                        %Datos de xsens y sensores
                        [Data ] = load_mvnx_v2(fullpathname);
                        Archivo=[ ubicacion '\Informacion De la Sesion(.mvnx).xls'];
                       
                        %--------------------------------------------Datos Generales----------------------------------------------
                        if isfield(Data,'metaData')
                        general = [{'MVN Version';'Sujeto';'Fecha';'Frecuencia de muestreo';'Calidad'},...
                            {Data.metaData.mvn_version;Data.metaData.subject_label;Data.metaData.subject_recDate;...
                            Data.metaData.subject_frameRate;Data.metaData.subject_processingQuality}];
                        
                        writecell(general,Archivo,'sheet','Datos Generales','Range','A2');
                        end
                        % --------------------------------------------SEGMENTOS---------------------------------------------------
                        if isfield(Data,'segmentData')
                                %-------------------------Orientación de los segmentos en Cuaterniones ---------------------------
                                if isfield(Data.segmentData,'orientation')
                                        Oriqu=[];y=0;
                                        for n=1:length(Data.segmentData)
                                            Oriqu=[Oriqu Data.segmentData(n).orientation]; %#ok<AGROW> 
                                            for t=0:3
                                                naOriqu{n+y}=[Data.segmentData(n).label 'q' num2str(t)]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naOriqu,Archivo,'sheet','Ori. Segmentos- Quat','Range','A1');
                                        writematrix(Oriqu,Archivo,'Sheet','Ori. Segmentos- Quat','WriteMode','append')
                                        clear t y n
                                end         
                                %---------------------------Posición de los segmentos en x y z-----------------------------------
                                if isfield(Data.segmentData,'position')
                                        Pos=[];y=0; eje=['x' 'y' 'z'];
                                        for n=1:length(Data.segmentData)
                                            Pos=[Pos Data.segmentData(n).position]; %#ok<AGROW> 
                                            for t=1:3
                                                naPos{n+y}=[Data.segmentData(n).label eje(t)]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naPos,Archivo,'sheet','Posición-Segmentos','Range','A1');
                                        writematrix(Pos,Archivo,'Sheet','Posición-Segmentos','WriteMode','append');
                                        clear t y n
                                end
                                %---------------------------Velocidad de los segmentos en x y z --------------------------------- 
                                if isfield(Data.segmentData,'velocity')
                                        Vel=[];y=0; eje=['x' 'y' 'z'];
                                        for n=1:length(Data.segmentData)
                                            Vel=[Vel Data.segmentData(n).velocity]; %#ok<AGROW> 
                                            for t=1:3
                                                naVel{n+y}=[Data.segmentData(n).label eje(t)]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naVel,Archivo,'sheet','Velocidad-Segmentos','Range','A1');
                                        writematrix(Vel,Archivo,'Sheet','Velocidad-Segmentos','WriteMode','append');
                                        clear t y n
                                end
                                %----------------------------Aceleración de los segmentos en x y z-------------------------------
                                if isfield(Data.segmentData,'acceleration')      
                                        Acc=[];y=0; eje=['x' 'y' 'z'];
                                        for n=1:length(Data.segmentData)
                                            Acc=[Acc Data.segmentData(n).acceleration]; %#ok<AGROW> 
                                            for t=1:3
                                                naAcc{n+y}=[Data.segmentData(n).label eje(t)]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naAcc,Archivo,'sheet','Aceleración-Segmentos','Range','A1');
                                        writematrix(Acc,Archivo,'Sheet','Aceleración-Segmentos','WriteMode','append');
                                        clear t y n
                                end       
                                %------------------------Velocidad Angular de los segmentos en x y z-----------------------------
                                if isfield(Data.segmentData,'angularVelocity')  
                                        Vang=[];y=0; eje=['x' 'y' 'z'];
                                        for n=1:length(Data.segmentData)
                                            Vang=[Vang Data.segmentData(n).angularVelocity]; %#ok<AGROW> 
                                            for t=1:3
                                                naVang{n+y}=[Data.segmentData(n).label eje(t)]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naVang,Archivo,'sheet','Vel. Angular-Segmentos','Range','A1');
                                        writematrix(Vang,Archivo,'Sheet','Vel. Angular-Segmentos','WriteMode','append');
                                        clear t y n
                                end
                                %------------------------Aceleracion Angular de los segmentos en x y z --------------------------
                                if isfield(Data.segmentData,'angularAcceleration')    
                                        Aang=[];y=0; eje=['x' 'y' 'z'];
                                        for n=1:length(Data.segmentData)
                                            Aang=[Aang Data.segmentData(n).angularAcceleration]; %#ok<AGROW> 
                                            for t=1:3
                                                naAang{n+y}=[Data.segmentData(n).label eje(t)]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naAang,Archivo,'sheet','Ace. Angular-Segmentos','Range','A1');
                                        writematrix(Aang,Archivo,'Sheet','Ace. Angular-Segmentos','WriteMode','append');
                                        clear t y n
                                end
                        end
                        %%------------------------------------------ARTICULACIÓNES ANGULOS NATURALES ----------------------------
                        if isfield(Data,'jointData')
                                %---------------------------------Angulo Articular ZXY-------------------------------------------
                                if isfield(Data.jointData,'jointAngle') 
                                        AaAZXY=[];y=0; eje=["lateral" "Axial" "Flexión/Extensión"];
                                        for n=1:length(Data.jointData)
                                            AaAZXY=[AaAZXY Data.jointData(n).jointAngle]; %#ok<AGROW> 
                                            for t=1:3
                                                naAaAZXY{n+y}=[Data.jointData(n).label convertStringsToChars(eje(t))]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        
                                        writecell(naAaAZXY,Archivo,'sheet','Ángu. Articulares-ZXY','Range','A1');
                                        writematrix(AaAZXY,Archivo,'sheet','Ángu. Articulares-ZXY','WriteMode','append');
                                        clear t y n
                                end         
                                %----------------------------------Angulo Articular XZY------------------------------------------
                                if isfield(Data.jointData,'jointAngleXZY')        
                                        AaAXZY=[];y=0; eje=["lateral" "Axial" "Flexión/Extensión"];
                                        for n=1:length(Data.jointData)
                                            AaAXZY=[AaAXZY Data.jointData(n).jointAngleXZY]; %#ok<AGROW> 
                                            for t=1:3
                                                naAaAXZY{n+y}=[Data.jointData(n).label convertStringsToChars(eje(t))]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naAaAXZY,Archivo,'sheet','Ángu. Articulares-XZY','Range','A1');
                                        writematrix(AaAXZY,Archivo,'sheet','Ángu. Articulares-XZY','WriteMode','append');
                                        clear t y n
                                end
                        end
                        %%-----------------------------ARTICULACIÓNES ANGULOS ERGONOMICOS----------------------------------------
                        if isfield(Data,'ergonomicJointAngle')
                                %-----------------------Angulo Ergonomicos Articular ZXY-----------------------------------------
                                if isfield(Data.ergonomicJointAngle,'jointAngleErgo')
                                        AaAEZXY=[];y=0; eje=["lateral" "Axial" "Flexión/Extensión"];
                                        for n=1:length(Data.ergonomicJointAngle)
                                            AaAEZXY=[AaAEZXY Data.ergonomicJointAngle(n).jointAngleErgo  ]; %#ok<AGROW> 
                                            for t=1:3
                                                naAaAEZXY{n+y}=[Data.ergonomicJointAngle(n).label convertStringsToChars(eje(t))]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naAaAEZXY,Archivo,'sheet','Ángu. Ergonomicos-ZXY','Range','A1');
                                        writematrix(AaAEZXY,Archivo,'sheet','Ángu. Ergonomicos-ZXY','WriteMode','append');
                                        clear t y n
                                end
                                %--------------------------------Angulo Ergonomicos Articular XZY-------------------------------- 
                                if isfield(Data.ergonomicJointAngle,'jointAngleErgoXZY')
                                        AaAEXZY=[];y=0; eje=["lateral" "Axial" "Flexión/Extensión"];
                                        for n=1:length(Data.ergonomicJointAngle)
                                            AaAEXZY=[AaAEXZY Data.ergonomicJointAngle(n).jointAngleErgoXZY  ]; %#ok<AGROW> 
                                            for t=1:3
                                                naAaAEXZY{n+y}=[Data.ergonomicJointAngle(n).label convertStringsToChars(eje(t))]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naAaAEXZY,Archivo,'sheet','Ángu. Ergonomicos-XZY','Range','A1');
                                        writematrix(AaAEXZY,Archivo,'sheet','Ángu. Ergonomicos-XZY','WriteMode','append');
                                        clear t y n
                                end
                        end
                        %%------------------------------------------DATOS SENSORES-----------------------------------------------
                        if isfield(Data,'sensorData')
                                %---------------------------------------Orientación Sensores------------------------------------- 
                                if isfield(Data.sensorData,'sensorOrientation')
                                        OS=[];y=0; eje=["q1" "q2" "q3" "q4"];
                                        for n=1:length(Data.sensorData)
                                            OS=[OS Data.sensorData(n).sensorOrientation  ]; %#ok<AGROW> 
                                            for t=1:4
                                                naOS{n+y}=[Data.sensorData(n).label convertStringsToChars(eje(t))]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naOS,Archivo,'sheet','Orientación-Sensores','Range','A1');
                                        writematrix(OS,Archivo,'sheet','Orientación-Sensores','WriteMode','append');
                                        clear t y n
                                end
                                %-------------------------------Aceleracion Sensores---------------------------------------------
                                if isfield(Data.sensorData,'sensorFreeAcceleration')        
                                        OA=[];y=0; eje=["X" "Y" "Z"];
                                        for n=1:length(Data.sensorData)
                                            OA=[OA Data.sensorData(n).sensorFreeAcceleration]; %#ok<AGROW> 
                                            for t=1:3
                                                naOA{n+y}=[Data.sensorData(n).label convertStringsToChars(eje(t))]; %#ok<AGROW> 
                                                y=y+1;
                                            end
                                            y=y-1;
                                        end
                                        writecell(naOA,Archivo,'sheet','Aceleración-Sensores','Range','A1');
                                        writematrix(OA,Archivo,'sheet','Aceleración-Sensores','WriteMode','append');
                                        clear t y n
                                end
                        end
                    
                    end
                    
                    export.Value = .80;
                    export.Message = 'Exportando Información a carpeta seleccionada';
                    pause(1)
           %------------------------------------------------------Datos Antropometricos-------------------------------------------         
                    if eleccion(5,1)==1
                        antror=which( 'Antropometricos.mat');
                            if length(antror)>1 
                            datos=load(antror);
                            datos2=datos.Data; 
                            Archivo3=[ ubicacion '\Datos Antropometricos.xls'];
                                                                     
                            %Datos Generales
                            NombreAntro = [{'Estatura (cm):';'Ancho de cadera (cm):';'Altura de cadera (cm):';...
                                               'Altura de rodilla (cm):';'Altura de tobillo (cm):';'Largo del pie (cm):';'Peso (kg):'}]; %#ok<NBRAK>
                            writecell(NombreAntro,Archivo3,'sheet','Datos Antropometricos','Range','B2');
                                  
                            DataAntro = [{datos2(1,1);datos2(2,1);datos2(3,1);datos2(4,1);datos2(5,1);datos2(6,1);datos2(7,1)}]; %#ok<NBRAK>
                            writecell(DataAntro,Archivo3,'sheet','Datos Antropometricos','Range','C2');
                       
                            end
                    end
                    export.Value = 1;
                    export.Message = 'Exportación completa';
                    pause(1)
            end
        end

        % Button pushed function: UsermanualButton
        function UsermanualButtonPushed(app, event)
            winopen('ManualdeUsuarioASPEBI.pdf');
        end

        % Button pushed function: CalcularEspaciotemporales_2
        function CalcularEspaciotemporales_2ButtonPushed(app, event)
            global Datos_segmentos sesion PIESB            
            
            %% ----------------------------PARAMETROS DESPUES---------------------------
            %Detección de largo de paso por contacto inicial
            [xpas111B]=find(PIESB==1001); %pie izquierdo
            [xpas222B]=find(PIESB==0110); %pie derecho
            xpas11B= [xpas111B;0]-[0;xpas111B];
            xpas22B= [xpas222B;0]-[0;xpas222B];
            xpas1B=find(xpas11B~=1);
            xpas2B=find(xpas22B~=1);
            framexpasIzqB=xpas111B([xpas1B(1:end-1);(xpas1B(end)-1)]);
            framexpasDerB=xpas222B([xpas2B(1:end-1);(xpas2B(end)-1)]);
            %%
            %Detección de balanceo inicial 
            [xba111B]=find(PIESB==0001);
            xba11B=[xba111B;0]-[0;xba111B];
            xba1B=find(xba11B~=1);
            frameBalanceoIzqB=xba111B([xba1B(1:end-1);(xba1B(end)-1)]);
            %% Parámetros espaciotemporales 
            %Tiempo de marcha 
            TMarchaB=length(PIESB)/str2double(sesion.metaData.subject_frameRate);
            %Largo de zancada 
            PosPasosIzqB=Datos_segmentos(22).position(framexpasIzqB,1);  
            ZancadaIzqB = abs(diff(abs(PosPasosIzqB)));
            LzancadaIzqB = ZancadaIzqB(3);
            app.LeftstridelengthEditField.Value = LzancadaIzqB;
            PosPasosDerB = Datos_segmentos(18).position(framexpasDerB,1);
            ZancadaDerB = abs(diff(abs(PosPasosDerB)));
            LzancadaDerB = median(ZancadaDerB);
            app.RigthstridelengthEditField.Value = LzancadaDerB;
            
            %Largo de paso 
            if (length(PosPasosIzqB)==length(PosPasosDerB))
                
            LargoPasoB = abs(abs(PosPasosIzqB)-abs(PosPasosDerB));
            LpasoB = median(LargoPasoB); 
            
            PasoDerB = Datos_segmentos(18).position(framexpasDerB,2);
            PasoIzqB = Datos_segmentos(22).position(framexpasIzqB,2);
            AnchoPasoB = median (abs(abs(PasoDerB)-abs(PasoIzqB)));
            else 
                paulaB=length(PosPasosIzqB)<length(PosPasosDerB);
                if paulaB ==1
                %Largo de paso 
                LargoPasoB = abs(abs(PosPasosIzqB)-abs(PosPasosDerB(1:length(PosPasosIzqB))));
                LpasoB = median(LargoPasoB); 
                
                %Ancho de paso 
                PasoDerB = Datos_segmentos(18).position(framexpasDerB,2);
                PasoIzqB = Datos_segmentos(22).position(framexpasIzqB,2);
                AnchoPasoB = median (abs(abs(PasoDerB(1:length(PosPasosIzqB)))-abs(PasoIzqB)));
                else
                    
                LargoPasoB = abs(abs(PosPasosIzqB(1:length(PosPasosDerB)))-abs(PosPasosDerB));
                LpasoB = median(LargoPasoB); 
            
                %Ancho de paso 
                PasoDerB = Datos_segmentos(18).position(framexpasDerB,2);
                PasoIzqB = Datos_segmentos(22).position(framexpasIzqB,2);
                AnchoPasoB = median (abs(abs(PasoDerB)-abs(PasoIzqB(1:length(PosPasosDerB)))));
                end
            end
            app.SteplengthEditField.Value = LpasoB;
            app.StepwidthEditField.Value = AnchoPasoB;
            %Cadencia 
            NumPasosB = length(framexpasIzqB) + length(framexpasDerB);
            CadenciaB =(NumPasosB*60)/TMarchaB;
            app.CadenceEditField.Value = CadenciaB;
            %Velocidad 
            VelocidadB=max(abs(sesion.segmentData(1).position(:,1)))/TMarchaB;
            app.Velocidaddemarcha.Value = VelocidadB;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {740, 740};
                app.GridLayout.ColumnWidth = {'1x'};
                app.Panel2.Layout.Row = 2;
                app.Panel2.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {169, '1x'};
                app.Panel2.Layout.Row = 1;
                app.Panel2.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.GridLayout);
            app.Panel1Grid = uigridlayout(app.Panel1);
            app.Registro = uibutton(app.Panel1Grid, 'push');
            app.Registro.ButtonPushedFcn = createCallbackFcn(app, @RegistroButtonPushed, true);
            app.Registro.Text = 'Register';
            app.Protocolo = uibutton(app.Panel1Grid, 'push');
            app.Protocolo.ButtonPushedFcn = createCallbackFcn(app, @ProtocoloButtonPushed, true);
            app.Protocolo.Text = 'Protocol';
            app.ReproduccionPanel1 = uibutton(app.Panel1Grid, 'push');
            app.ReproduccionPanel1.ButtonPushedFcn = createCallbackFcn(app, @ReproduccionPanel1ButtonPushed, true);
            app.ReproduccionPanel1.Text = 'Reproduction';
            app.Fecha2 = uilabel(app.Panel1Grid);
            app.Fecha2.Text = 'Date';
            app.Inicio = uibutton(app.Panel1Grid, 'push');
            app.Inicio.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.Inicio.Text = 'Start';
            app.ASPEBI1 = uiimage(app.Panel1Grid);
            app.ASPEBI1.ImageSource = 'ASPEBI1.jpeg';
            app.Fecha = uilabel(app.Panel1Grid);
            app.Fecha.Text = '';
            app.Exportar = uibutton(app.Panel1Grid, 'push');
            app.Exportar.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.Exportar.Text = 'Export';
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2Grid = uigridlayout(app.Panel2);
            app.Opciones = uitabgroup(app.Panel2Grid);
            app.InicioOpc = uitab(app.Opciones);
            app.InicioOpc.Title = 'Start';
            app.InicioOpcGrid = uigridlayout(app.InicioOpc);
            app.Comoempezar = uibutton(app.InicioOpcGrid, 'push');
            app.Comoempezar.ButtonPushedFcn = createCallbackFcn(app, @ComoempezarPushed, true);
            app.Comoempezar.Text = 'How to start?';
            app.Contctenos = uibutton(app.InicioOpcGrid, 'push');
            app.Contctenos.ButtonPushedFcn = createCallbackFcn(app, @ContctenosPushed, true);
            app.Contctenos.Text = 'Contact us';
            app.Image3 = uiimage(app.InicioOpcGrid);
            app.Image3.ImageSource = 'FONDO.jpeg';
            app.Image2 = uiimage(app.InicioOpcGrid);
            app.Image2.ImageSource = 'LOGOBOSQUE.jpg';
            app.Image4 = uiimage(app.InicioOpcGrid);
            app.Image4.ImageSource = 'ASPEBI2.jpeg';
            app.Intro = uilabel(app.InicioOpcGrid);
            app.Intro.Text = 'ASPEBI is a visualization and analysis tool designed for the study of gait parameters in patients with mechanical non-specific low back pain before and after performing the bipedal hamstring extension test (PEBI) using inertial measurement units. ';
            app.UsermanualButton = uibutton(app.InicioOpcGrid, 'push');
            app.UsermanualButton.ButtonPushedFcn = createCallbackFcn(app, @UsermanualButtonPushed, true);
            app.UsermanualButton.Text = 'User manual';
            app.RegisterTab = uitab(app.Opciones);
            app.RegisterTab.Title = 'Register';
            app.GridLayout2 = uigridlayout(app.RegisterTab);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.ResearcherTab = uitab(app.TabGroup);
            app.ResearcherTab.Title = 'Researcher';
            app.GridInvestigador = uigridlayout(app.ResearcherTab);
            app.RegistrarInvestigador = uibutton(app.GridInvestigador, 'push');
            app.RegistrarInvestigador.ButtonPushedFcn = createCallbackFcn(app, @RegistrarInvestigadorButtonPushed, true);
            app.RegistrarInvestigador.Text = 'Register';
            app.RestablecerInvestigador = uibutton(app.GridInvestigador, 'push');
            app.RestablecerInvestigador.ButtonPushedFcn = createCallbackFcn(app, @RestablecerInvestigadorButtonPushed, true);
            app.RestablecerInvestigador.Text = 'Clean';
            app.OkInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.OkInvestigador.Editable = 'off';
            app.OkInvestigador.Value = '...';
            app.TIDInvestigador = uidropdown(app.GridInvestigador);
            app.TIDInvestigador.Items = {'--Seleccione--', 'TI', 'CC', 'CE', 'CD', 'PA', 'SC', 'PE', 'RC', 'CN', 'AS', 'MS'};
            app.TIDInvestigador.Value = '--Seleccione--';
            app.TipodedocumentoLabel_3 = uilabel(app.GridInvestigador);
            app.TipodedocumentoLabel_3.Text = 'ID type';
            app.FirstnameLabel = uilabel(app.GridInvestigador);
            app.FirstnameLabel.Text = 'First name';
            app.PNInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.MiddlenameLabel = uilabel(app.GridInvestigador);
            app.MiddlenameLabel.Text = 'Middle name';
            app.SNInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.FirstsurnameLabel = uilabel(app.GridInvestigador);
            app.FirstsurnameLabel.Text = 'First surname';
            app.PAInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.SecondlastnameLabel = uilabel(app.GridInvestigador);
            app.SecondlastnameLabel.Text = 'Second surname';
            app.SAInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.BirthdateLabel = uilabel(app.GridInvestigador);
            app.BirthdateLabel.Text = 'Birth date';
            app.FNInvestigador = uidatepicker(app.GridInvestigador);
            app.FNInvestigador.DisplayFormat = 'dd-MMM-uuuu';
            app.FNInvestigador.ValueChangedFcn = createCallbackFcn(app, @FNInvestigadorValueChanged, true);
            app.GenderLabel = uilabel(app.GridInvestigador);
            app.GenderLabel.Text = 'Gender';
            app.SexoInvestigador = uidropdown(app.GridInvestigador);
            app.SexoInvestigador.Items = {'--Seleccione--', 'F', 'M'};
            app.SexoInvestigador.Value = '--Seleccione--';
            app.ResidencezoneLabel = uilabel(app.GridInvestigador);
            app.ResidencezoneLabel.Text = 'Residence zone';
            app.ZRInvestigador = uidropdown(app.GridInvestigador);
            app.ZRInvestigador.Items = {'--Seleccione--', 'U ', 'R'};
            app.ZRInvestigador.Value = '--Seleccione--';
            app.PEBISwitch_3Label = uilabel(app.GridInvestigador);
            app.PEBISwitch_3Label.Text = 'PEBI';
            app.PEBIInvestigador = uiswitch(app.GridInvestigador, 'slider');
            app.PEBIInvestigador.Items = {'-', '+'};
            app.PEBIInvestigador.Value = '-';
            app.MaximumlevelofeducationLabel = uilabel(app.GridInvestigador);
            app.MaximumlevelofeducationLabel.Text = 'Maximum level of education ';
            app.NivelmximodeescolaridadInvestigador = uidropdown(app.GridInvestigador);
            app.NivelmximodeescolaridadInvestigador.Items = {'--Seleccione--', 'No  bachiller ', 'Bachiller', 'Técnico o tecnológico', 'Universitario ', 'Posgrado ', 'No define'};
            app.NivelmximodeescolaridadInvestigador.Value = '--Seleccione--';
            app.NodedocumentoLabel = uilabel(app.GridInvestigador);
            app.NodedocumentoLabel.Text = 'ID number';
            app.IDInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.EdadLabel = uilabel(app.GridInvestigador);
            app.EdadLabel.Text = 'Age';
            app.EdadInvestigador = uieditfield(app.GridInvestigador, 'text');
            app.MedicoTab = uitab(app.TabGroup);
            app.MedicoTab.Title = 'Physician';
            app.GridMedico = uigridlayout(app.MedicoTab);
            app.RegistrodeparticipanteMedico = uipanel(app.GridMedico);
            app.RegistrodeparticipanteMedico.Title = 'Registro de participante';
            app.GridRegistroMedico = uigridlayout(app.RegistrodeparticipanteMedico);
            app.OkParticipanteMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.OkParticipanteMedico.Editable = 'off';
            app.RestablecerParticipanteMedico = uibutton(app.GridRegistroMedico, 'push');
            app.RestablecerParticipanteMedico.ButtonPushedFcn = createCallbackFcn(app, @RestablecerParticipanteMedicoButtonPushed, true);
            app.RestablecerParticipanteMedico.Text = 'Clear';
            app.EnviarParticipanteMedico = uibutton(app.GridRegistroMedico, 'push');
            app.EnviarParticipanteMedico.ButtonPushedFcn = createCallbackFcn(app, @EnviarParticipanteMedicoButtonPushed, true);
            app.EnviarParticipanteMedico.Text = 'Register';
            app.TipodedocumentoLabel_4 = uilabel(app.GridRegistroMedico);
            app.TipodedocumentoLabel_4.Text = 'ID type';
            app.TIDMedico = uidropdown(app.GridRegistroMedico);
            app.TIDMedico.Items = {'--Seleccione--', 'TI', 'CC', 'CE', 'CD', 'PA', 'SC', 'PE', 'RC', 'CN', 'AS', 'MS'};
            app.TIDMedico.Value = '--Seleccione--';
            app.SNMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.MiddlenameLabel_2 = uilabel(app.GridRegistroMedico);
            app.MiddlenameLabel_2.Text = 'Middle name';
            app.PAMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.FirstsurnameLabel_2 = uilabel(app.GridRegistroMedico);
            app.FirstsurnameLabel_2.Text = 'First surname';
            app.SAMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.SecondsurnameLabel = uilabel(app.GridRegistroMedico);
            app.SecondsurnameLabel.Text = 'Second surname ';
            app.FNMedico = uidatepicker(app.GridRegistroMedico);
            app.FNMedico.DisplayFormat = 'dd-MMM-uuuu';
            app.FNMedico.ValueChangedFcn = createCallbackFcn(app, @FNMedicoValueChanged, true);
            app.BirthdateLabel_2 = uilabel(app.GridRegistroMedico);
            app.BirthdateLabel_2.Text = 'Birth date';
            app.UsertypeLabel = uilabel(app.GridRegistroMedico);
            app.UsertypeLabel.Text = 'User type';
            app.TUMedico = uidropdown(app.GridRegistroMedico);
            app.TUMedico.Items = {'--Seleccione--', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.TUMedico.Value = '--Seleccione--';
            app.CodDMedico = uieditfield(app.GridRegistroMedico, 'numeric');
            app.CodDMedico.Value = 11;
            app.StatecodeLabel = uilabel(app.GridRegistroMedico);
            app.StatecodeLabel.Text = 'State code';
            app.CodMMedico = uieditfield(app.GridRegistroMedico, 'numeric');
            app.CodMMedico.Value = 1;
            app.TowncodeLabel = uilabel(app.GridRegistroMedico);
            app.TowncodeLabel.Text = 'Town code';
            app.SexoMedico = uidropdown(app.GridRegistroMedico);
            app.SexoMedico.Items = {'--Seleccione--', 'F', 'M'};
            app.SexoMedico.Value = '--Seleccione--';
            app.GenderLabel_2 = uilabel(app.GridRegistroMedico);
            app.GenderLabel_2.Text = 'Gender';
            app.ZRMedico = uidropdown(app.GridRegistroMedico);
            app.ZRMedico.Items = {'--Seleccione--', 'U ', 'R'};
            app.ZRMedico.Value = '--Seleccione--';
            app.ResidencezonreLabel = uilabel(app.GridRegistroMedico);
            app.ResidencezonreLabel.Text = 'Residence zonre';
            app.CEAMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.EntidadadministradoraLabel = uilabel(app.GridRegistroMedico);
            app.EntidadadministradoraLabel.Text = 'Admin entity';
            app.PEBIMedico = uiswitch(app.GridRegistroMedico, 'slider');
            app.PEBIMedico.Items = {'-', '+'};
            app.PEBIMedico.Value = '-';
            app.PEBISwitchLabel = uilabel(app.GridRegistroMedico);
            app.PEBISwitchLabel.Text = 'PEBI';
            app.EdadMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.EdadLabel_2 = uilabel(app.GridRegistroMedico);
            app.EdadLabel_2.Text = 'Age';
            app.UEMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.UnidaddeedadLabel = uilabel(app.GridRegistroMedico);
            app.UnidaddeedadLabel.Text = 'Age unit';
            app.IDMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.NodedocumentoLabel_2 = uilabel(app.GridRegistroMedico);
            app.NodedocumentoLabel_2.Text = 'ID number';
            app.PNMedico = uieditfield(app.GridRegistroMedico, 'text');
            app.PrimernombreLabel = uilabel(app.GridRegistroMedico);
            app.PrimernombreLabel.Text = 'First name';
            app.UEdadMedico = uilabel(app.GridRegistroMedico);
            app.UEdadMedico.Text = '';
            app.ConsultaMedico = uipanel(app.GridMedico);
            app.ConsultaMedico.Title = 'Consulta ';
            app.GridLayout3 = uigridlayout(app.ConsultaMedico);
            app.RestablecerConsultaMedico = uibutton(app.GridLayout3, 'push');
            app.RestablecerConsultaMedico.ButtonPushedFcn = createCallbackFcn(app, @RestablecerConsultaMedicoButtonPushed, true);
            app.RestablecerConsultaMedico.Text = 'Clear';
            app.EnviarConsultaMedico = uibutton(app.GridLayout3, 'push');
            app.EnviarConsultaMedico.ButtonPushedFcn = createCallbackFcn(app, @EnviarConsultaMedicoButtonPushed, true);
            app.EnviarConsultaMedico.Text = 'Register';
            app.CDRLabel = uilabel(app.GridLayout3);
            app.CDRLabel.Text = 'Related Diagnostic Code';
            app.OkConsultaMedico = uieditfield(app.GridLayout3, 'text');
            app.OkConsultaMedico.Editable = 'off';
            app.NAutorizacionConsultaMedico = uitextarea(app.GridLayout3);
            app.NAutorizacionConsultaMedico.Editable = 'off';
            app.NAutorizacionConsultaMedico.Visible = 'off';
            app.NdeautorizacinLabel = uilabel(app.GridLayout3);
            app.NdeautorizacinLabel.Visible = 'off';
            app.NdeautorizacinLabel.Text = 'N. de autorización';
            app.CDPConsultaMedico = uieditfield(app.GridLayout3, 'text');
            app.CdigodediagnsticoprincipalCDPLabel = uilabel(app.GridLayout3);
            app.CdigodediagnsticoprincipalCDPLabel.Text = 'Main diagnostic code ';
            app.FConsultaMedico = uidropdown(app.GridLayout3);
            app.FConsultaMedico.Items = {'--Seleccione--', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10'};
            app.FConsultaMedico.Value = '--Seleccione--';
            app.ConsultationpLabel = uilabel(app.GridLayout3);
            app.ConsultationpLabel.Text = 'Consultation purpose';
            app.CExternaMedico = uidropdown(app.GridLayout3);
            app.CExternaMedico.Items = {'--Seleccione--', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15'};
            app.CExternaMedico.Value = '--Seleccione--';
            app.ExternalcauseLabel = uilabel(app.GridLayout3);
            app.ExternalcauseLabel.Text = 'External cause ';
            app.TDiagnosticoMedico = uidropdown(app.GridLayout3);
            app.TDiagnosticoMedico.Items = {'--Seleccione--', '1', '2', '3'};
            app.TDiagnosticoMedico.Value = '--Seleccione--';
            app.DiagnosictypeLabel = uilabel(app.GridLayout3);
            app.DiagnosictypeLabel.Text = 'Diagnosic type';
            app.CDR1Consultamedico = uieditfield(app.GridLayout3, 'text');
            app.No1EditFieldLabel = uilabel(app.GridLayout3);
            app.No1EditFieldLabel.Text = 'No. 1';
            app.CDR2ConsultaMedico = uieditfield(app.GridLayout3, 'text');
            app.No2EditFieldLabel = uilabel(app.GridLayout3);
            app.No2EditFieldLabel.Text = 'No. 2';
            app.CDR3ConsultaMedico = uieditfield(app.GridLayout3, 'text');
            app.No3EditFieldLabel = uilabel(app.GridLayout3);
            app.No3EditFieldLabel.Text = 'No. 3';
            app.CConsultaMedico = uieditfield(app.GridLayout3, 'text');
            app.CConsultaLabel = uilabel(app.GridLayout3);
            app.CConsultaLabel.Text = 'Consultation code';
            app.ValorCConsultaMedico = uieditfield(app.GridLayout3, 'numeric');
            app.ConsultationvalueLabel = uilabel(app.GridLayout3);
            app.ConsultationvalueLabel.Text = 'Consultation value ';
            app.ValorCMConsultaMedico = uieditfield(app.GridLayout3, 'numeric');
            app.ValorcmoderadoraLabel = uilabel(app.GridLayout3);
            app.ValorcmoderadoraLabel.Text = 'Fee';
            app.ValorNConsultaMedico = uieditfield(app.GridLayout3, 'numeric');
            app.PayablevalueLabel = uilabel(app.GridLayout3);
            app.PayablevalueLabel.Text = 'Payable value';
            app.ADVCDPConsultaMedico = uilabel(app.GridLayout3);
            app.ADVCDPConsultaMedico.Text = '';
            app.ADVCDR1ConsutlaMedico = uilabel(app.GridLayout3);
            app.ADVCDR1ConsutlaMedico.Text = '';
            app.ADVCDR2ConsutlaMedico = uilabel(app.GridLayout3);
            app.ADVCDR2ConsutlaMedico.Text = '';
            app.ADVCDR3ConsutlaMedico = uilabel(app.GridLayout3);
            app.ADVCDR3ConsutlaMedico.Text = '';
            app.ADVVCMConsultaMedico = uilabel(app.GridLayout3);
            app.ADVVCMConsultaMedico.Text = '';
            app.ADVVCConsultaMedico = uilabel(app.GridLayout3);
            app.ADVVCConsultaMedico.Text = '';
            app.ADVVNPConsultaMedico = uilabel(app.GridLayout3);
            app.ADVVNPConsultaMedico.Text = '';
            app.ADVFCConsultaMedico = uilabel(app.GridLayout3);
            app.ADVFCConsultaMedico.Text = '';
            app.ProcedimientoMedico = uipanel(app.GridMedico);
            app.ProcedimientoMedico.Title = 'Procedimiento ';
            app.GridLayout5 = uigridlayout(app.ProcedimientoMedico);
            app.OkProecedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.OkProecedimientoMedico.Editable = 'off';
            app.RestablecerProcedimientoMedico = uibutton(app.GridLayout5, 'push');
            app.RestablecerProcedimientoMedico.ButtonPushedFcn = createCallbackFcn(app, @RestablecerProcedimientoMedicoButtonPushed, true);
            app.RestablecerProcedimientoMedico.Text = 'Clear';
            app.EnviarProcedimientoMedico = uibutton(app.GridLayout5, 'push');
            app.EnviarProcedimientoMedico.ButtonPushedFcn = createCallbackFcn(app, @EnviarProcedimientoMedicoButtonPushed, true);
            app.EnviarProcedimientoMedico.Text = 'Register';
            app.CProcedimientoProcedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.ProcedurecodeLabel = uilabel(app.GridLayout5);
            app.ProcedurecodeLabel.Text = 'Procedure code';
            app.APRProcedimientoMedico = uidropdown(app.GridLayout5);
            app.APRProcedimientoMedico.Items = {'1', '2', '3', '4', '5'};
            app.APRProcedimientoMedico.Value = '1';
            app.mbitodeprocedimientoLabel = uilabel(app.GridLayout5);
            app.mbitodeprocedimientoLabel.Text = 'Scope of procedure ';
            app.DPProcedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.MaindiagnosisLabel = uilabel(app.GridLayout5);
            app.MaindiagnosisLabel.Text = 'Main diagnosis ';
            app.ComplicacionProcedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.ComplicacinLabel = uilabel(app.GridLayout5);
            app.ComplicacinLabel.Text = 'Complication';
            app.FRAQProcedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.SurgicalprocedureLabel = uilabel(app.GridLayout5);
            app.SurgicalprocedureLabel.Text = 'Surgical procedure ';
            app.NAutorizacionProcedimientoMedico = uitextarea(app.GridLayout5);
            app.NmerodeautorizacinLabel = uilabel(app.GridLayout5);
            app.NmerodeautorizacinLabel.Text = 'Authorization number ';
            app.DRProcedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.DiagnosticorelacionadoLabel = uilabel(app.GridLayout5);
            app.DiagnosticorelacionadoLabel.Text = 'Related diagnosis';
            app.FinalidaddelprocedimientoLabel = uilabel(app.GridLayout5);
            app.FinalidaddelprocedimientoLabel.Text = 'Procedure purpose';
            app.FPProcedimientoMedico = uidropdown(app.GridLayout5);
            app.FPProcedimientoMedico.Items = {'--Seleccione--', '1', '2', '3', '4', '5'};
            app.FPProcedimientoMedico.Value = '--Seleccione--';
            app.PersonalqueatiendeLabel = uilabel(app.GridLayout5);
            app.PersonalqueatiendeLabel.Text = 'Staff code';
            app.PersonalAProcedimientoMedico = uidropdown(app.GridLayout5);
            app.PersonalAProcedimientoMedico.Items = {'--Seleccione--', '1', '2', '3', '4', '5'};
            app.PersonalAProcedimientoMedico.Value = '--Seleccione--';
            app.VNPProcedimientoMedico = uieditfield(app.GridLayout5, 'numeric');
            app.ProcedurefeeLabel = uilabel(app.GridLayout5);
            app.ProcedurefeeLabel.Text = 'Procedure fee';
            app.CIPSProcedimientoMedico = uieditfield(app.GridLayout5, 'text');
            app.CdigodeIPSLabel = uilabel(app.GridLayout5);
            app.CdigodeIPSLabel.Text = 'IPS code';
            app.NFacturaProcedimientoMedico = uitextarea(app.GridLayout5);
            app.NmerodefacturaLabel = uilabel(app.GridLayout5);
            app.NmerodefacturaLabel.Text = 'Invoice number';
            app.ProtocolTab = uitab(app.Opciones);
            app.ProtocolTab.Title = 'Protocol';
            app.GridLayout2_2 = uigridlayout(app.ProtocolTab);
            app.UIAxes = uiaxes(app.GridLayout2_2);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PreviousButton = uibutton(app.GridLayout2_2, 'state');
            app.PreviousButton.ValueChangedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout2_2, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.NextButton.Text = 'Next';
            app.PrimerPaso = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso.Text = 'Turn on MTw Awinda';
            app.PrimerPaso_3 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_3.Text = 'Put on the sensors in the participant body';
            app.PrimerPaso_2 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_2.Text = 'Enter anthropometric measurements ';
            app.PrimerPaso_4 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_4.Text = 'Calibrate sensors';
            app.PrimerPaso_5 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_5.Text = 'Execute PEBI';
            app.PrimerPaso_6 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_6.Text = 'Save .mnvx file';
            app.PrimerPaso_8 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_8.Text = 'Upload .mvnx file in reproduction option';
            app.PrimerPaso_9 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_9.Text = 'Start motion reproduction';
            app.PrimerPaso_7 = uicheckbox(app.GridLayout2_2);
            app.PrimerPaso_7.Text = 'Remove sensors';
            app.StageLabel = uilabel(app.GridLayout2_2);
            app.StageLabel.Text = 'Stage';
            app.Indicacion = uilabel(app.GridLayout2_2);
            app.Indicacion.Text = '';
            app.EnteranthropometricdataButton = uibutton(app.GridLayout2_2, 'push');
            app.EnteranthropometricdataButton.ButtonPushedFcn = createCallbackFcn(app, @EnteranthropometricdataButtonPushed, true);
            app.EnteranthropometricdataButton.Text = 'Enter anthropometric data';
            app.ReproductionTab = uitab(app.Opciones);
            app.ReproductionTab.Title = 'Reproduction';
            app.GridLayout9 = uigridlayout(app.ReproductionTab);
            app.ImportDataPanel = uipanel(app.GridLayout9);
            app.ImportDataPanel.Title = 'Import Data';
            app.GridLayout10 = uigridlayout(app.ImportDataPanel);
            app.CargarArchivoMVNX = uibutton(app.GridLayout10, 'push');
            app.CargarArchivoMVNX.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoMVNXButtonPushed, true);
            app.CargarArchivoMVNX.Text = 'Upload file (.mvnx)';
            app.TypeoffileButtonGroup = uibuttongroup(app.GridLayout10);
            app.TypeoffileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeoffileButtonGroupSelectionChanged, true);
            app.TypeoffileButtonGroup.Title = 'Type of file ';
            app.PEBIImportar = uiradiobutton(app.TypeoffileButtonGroup);
            app.PEBIImportar.Text = 'PEBI';
            app.PEBIImportar.Value = true;
            app.VariablesImportar = uiradiobutton(app.TypeoffileButtonGroup);
            app.VariablesImportar.Text = 'Hip rotation excercise';
            app.SpatiotemporalvariablesPanel = uipanel(app.GridLayout9);
            app.SpatiotemporalvariablesPanel.Title = 'Spatiotemporal variables';
            app.GridLayout12 = uigridlayout(app.SpatiotemporalvariablesPanel);
            app.SteplengthEditFieldLabel = uilabel(app.GridLayout12);
            app.SteplengthEditFieldLabel.Text = 'Step length';
            app.SteplengthEditField = uieditfield(app.GridLayout12, 'numeric');
            app.CadenceEditFieldLabel = uilabel(app.GridLayout12);
            app.CadenceEditFieldLabel.Text = 'Cadence';
            app.CadenceEditField = uieditfield(app.GridLayout12, 'numeric');
            app.GaitspeedLabel = uilabel(app.GridLayout12);
            app.GaitspeedLabel.Text = 'Gait speed';
            app.Velocidaddemarcha = uieditfield(app.GridLayout12, 'numeric');
            app.StepwidthEditFieldLabel = uilabel(app.GridLayout12);
            app.StepwidthEditFieldLabel.Text = 'Step width';
            app.StepwidthEditField = uieditfield(app.GridLayout12, 'numeric');
            app.RigthstridelengthEditFieldLabel = uilabel(app.GridLayout12);
            app.RigthstridelengthEditFieldLabel.Text = 'Rigth stride length';
            app.RigthstridelengthEditField = uieditfield(app.GridLayout12, 'numeric');
            app.CalcularEspaciotemporales = uibutton(app.GridLayout12, 'push');
            app.CalcularEspaciotemporales.ButtonPushedFcn = createCallbackFcn(app, @CalcularEspaciotemporalesPushed, true);
            app.CalcularEspaciotemporales.Text = 'Before';
            app.LeftstridelengthEditFieldLabel = uilabel(app.GridLayout12);
            app.LeftstridelengthEditFieldLabel.Text = 'Left stride length';
            app.LeftstridelengthEditField = uieditfield(app.GridLayout12, 'numeric');
            app.mLabel = uilabel(app.GridLayout12);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GridLayout12);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.GridLayout12);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.GridLayout12);
            app.mLabel_4.Text = 'm';
            app.ppmLabel = uilabel(app.GridLayout12);
            app.ppmLabel.Text = 'ppm';
            app.msLabel = uilabel(app.GridLayout12);
            app.msLabel.Text = 'm/s';
            app.CalcularEspaciotemporales_2 = uibutton(app.GridLayout12, 'push');
            app.CalcularEspaciotemporales_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularEspaciotemporales_2ButtonPushed, true);
            app.CalcularEspaciotemporales_2.Text = 'After';
            app.Reproduccin3DPanel = uipanel(app.GridLayout9);
            app.Reproduccin3DPanel.Title = 'Reproducción 3D';
            app.GridLayout13 = uigridlayout(app.Reproduccin3DPanel);
            app.Reproduccion = uiaxes(app.GridLayout13);
            xlabel(app.Reproduccion, 'X')
            ylabel(app.Reproduccion, 'Y')
            app.Tiempo = uislider(app.GridLayout13);
            app.Tiempo.Limits = [0.1 100];
            app.Tiempo.Value = 0.1;
            app.IniciarReproduccion = uibutton(app.GridLayout13, 'push');
            app.IniciarReproduccion.ButtonPushedFcn = createCallbackFcn(app, @IniciarReproduccionPushed, true);
            app.IniciarReproduccion.Text = 'Play';
            app.JointanglegraphsPanel = uipanel(app.GridLayout9);
            app.JointanglegraphsPanel.Title = 'Joint angle graphs ';
            app.GridLayout14 = uigridlayout(app.JointanglegraphsPanel);
            app.Axesgrafica = uiaxes(app.GridLayout14);
            xlabel(app.Axesgrafica, 'Time (s)')
            ylabel(app.Axesgrafica, 'Angle (º)')
            app.Angulosgraficas_2 = uidropdown(app.GridLayout14);
            app.Angulosgraficas_2.Items = {'Seleccionar', 'Hip extension/flexion', 'Aducción/Abducción Cadera', 'Rotación Cadera ', 'Flexión/Extensión Rodilla', 'Aducción/Abducción Rodilla', 'Rotación Rodilla', 'Dorsiflexión/Plantiflexión Tobillo ', 'Aducción/Abducción Tobillo', 'Rotación Tobillo'};
            app.Angulosgraficas_2.ValueChangedFcn = createCallbackFcn(app, @Angulosgraficas_2ValueChanged, true);
            app.Angulosgraficas_2.Value = 'Seleccionar';
            app.RotacinCadera = uicheckbox(app.GridLayout14);
            app.RotacinCadera.Text = 'Hip rotation';
            app.FlexinExtensinCadera = uicheckbox(app.GridLayout14);
            app.FlexinExtensinCadera.Text = 'Hip flexion / extension ';
            app.AduccinAbduccinCadera = uicheckbox(app.GridLayout14);
            app.AduccinAbduccinCadera.Text = 'Hip adduction/abduction';
            app.RotacinRodilla = uicheckbox(app.GridLayout14);
            app.RotacinRodilla.Text = 'Knee rotation';
            app.FlexinExtensinRodilla = uicheckbox(app.GridLayout14);
            app.FlexinExtensinRodilla.Text = 'Knee flexion/extension';
            app.RotacinTobillo = uicheckbox(app.GridLayout14);
            app.RotacinTobillo.Text = 'Ankle rotation';
            app.DorsiflexinPlantiflexinTobillo = uicheckbox(app.GridLayout14);
            app.DorsiflexinPlantiflexinTobillo.Text = 'Ankle dorsiflexion/plantiflexion';
            app.Ampliar = uibutton(app.GridLayout14, 'push');
            app.Ampliar.ButtonPushedFcn = createCallbackFcn(app, @AmpliarButtonPushed, true);
            app.Ampliar.Text = 'Fullscreen view';
            app.AduccinAbduccinRodilla = uicheckbox(app.GridLayout14);
            app.AduccinAbduccinRodilla.Text = 'Knee adduction/abduction';
            app.AduccinAbduccinTobillo = uicheckbox(app.GridLayout14);
            app.AduccinAbduccinTobillo.Text = 'Ankle adduction/abduction';
            app.ReportPanel = uipanel(app.GridLayout9);
            app.ReportPanel.Title = 'Report';
            app.GridLayout11 = uigridlayout(app.ReportPanel);
            app.VisualizarInforme = uibutton(app.GridLayout11, 'push');
            app.VisualizarInforme.ButtonPushedFcn = createCallbackFcn(app, @VisualizarInformeButtonPushed, true);
            app.VisualizarInforme.Text = 'Download';
            app.INICIOLabel = uilabel(app.Panel2Grid);
            app.INICIOLabel.Text = 'INICIO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASPEBI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end